classdef GUI_experiment_Manny_Jun9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        rasterResponse_axes            matlab.ui.control.UIAxes
        StartExperiment_Button         matlab.ui.control.StateButton
        handleGrasp_axes               matlab.ui.control.UIAxes
        SessionTypeButtonGroup         matlab.ui.container.ButtonGroup
        AssociationwithTactileStimButton  matlab.ui.control.RadioButton
        GraspingTrainingButton         matlab.ui.control.RadioButton
        DetectionButton                matlab.ui.control.RadioButton
        RandomRewardButton             matlab.ui.control.RadioButton
        HandleandSpoutDetectorsSwitch  matlab.ui.control.Switch
        InitializeDetectorsLabel       matlab.ui.control.Label
        HandleandSpoutDetectorsLamp    matlab.ui.control.Lamp
        DirectorytoSaveButton          matlab.ui.control.Button
        SaveDirLabel                   matlab.ui.control.Label
        LoadExperimentalFileButton     matlab.ui.control.Button
        NotLoadedLabel                 matlab.ui.control.Label
        ExperimentalFileLamp           matlab.ui.control.Lamp
        ParametersforRandomRewardAssociationTrainingSessionsPanel  matlab.ui.container.Panel
        StimtoRewardTimeDiffmsEditFieldLabel  matlab.ui.control.Label
        StimtoRewardTimeDiffmsEditField  matlab.ui.control.NumericEditField
        LoadStimulusFileButton         matlab.ui.control.Button
        LoadStimulusFileLamp           matlab.ui.control.Lamp
        NumberoftrialsEditFieldLabel   matlab.ui.control.Label
        NumberoftrialsEditField        matlab.ui.control.NumericEditField
        StimulusfileLabel              matlab.ui.control.Label
        ITIStartmsEditField_2Label     matlab.ui.control.Label
        ITIStartmsEditField            matlab.ui.control.NumericEditField
        ITIEndmsLabel                  matlab.ui.control.Label
        ITIEndmsEditField              matlab.ui.control.NumericEditField
        GrasptimeoutmsLabel            matlab.ui.control.Label
        GraspTimeOutEditField          matlab.ui.control.NumericEditField
        RewardParametersPanel          matlab.ui.container.Panel
        RewardSizeuLEditFieldLabel     matlab.ui.control.Label
        RewardSizeuLEditField          matlab.ui.control.NumericEditField
        SuckInLiquid_Button            matlab.ui.control.StateButton
        PushoutLiquid_Button           matlab.ui.control.StateButton
        DeliverRewardButton            matlab.ui.control.Button
        Switch                         matlab.ui.control.Switch
        ExperimentalFileLabel          matlab.ui.control.Label
        PostWeightgEditFieldLabel      matlab.ui.control.Label
        MousepostWeight_editBox        matlab.ui.control.NumericEditField
        MouseIDEditFieldLabel          matlab.ui.control.Label
        MouseIDEditField               matlab.ui.control.NumericEditField
        PreWeightgEditFieldLabel       matlab.ui.control.Label
        PreWeightgEditField            matlab.ui.control.NumericEditField
        StopExperimentButton           matlab.ui.control.Button
        OverheadLightsSwitchLabel      matlab.ui.control.Label
        OverheadLightsSwitch           matlab.ui.control.ToggleSwitch
        CameraLightsLabel              matlab.ui.control.Label
        CameraLightsSwitch             matlab.ui.control.ToggleSwitch
        CueLightSwitchLabel            matlab.ui.control.Label
        CueLightSwitch                 matlab.ui.control.ToggleSwitch
    end

    
    properties (Access = public)
        %TO access these variables below use the notation "app.variableName"
        % App parameters
        powerCapacitors = []; %variable for providing power to Left lick-spout capacitor via NI-DAQ
        powerCapacitors1 = []; %variable for providing power to Right lick-spout capacitor via NI-DAQ
        spout_and_handle_Detection = []; %variable for channels of handle and lick
        lh = []; %variable for listener
        fluidPump  = []; %GPIO for powering fluid pump
        fluidPump2 = []; %GPIO for powering fluid pump
        sizeCodes = [] %variable for reward size signal transmission to arduino
        spoutLicked_left = 0; %variable indicating whether the mouse licked the left spout
        spoutLicked_right = 0; %variable indicating whether the mouse licked the left spout
        handleGrasped_left = 0; %variable indicating whether the mouse grabbed the left handle
        handleGrasped_right = 0; %variable indicating whether the mouse grabbed the right handle
        startExperiment = nan;
        startTrialTime = nan;
        sessionStart = 0;
        total_licks_left = 0;
        total_licks_right = 0;
        total_grasps_right = 0;
        total_grasps_left = 0;
        experiment_started = 0; %flag for the stop experiment button
        floodLight = []; %5v power supply GPIO pin for powering the rig's overhead light
        cameraLight = [];  %5v power supply GPIO pin for powering the RaspberryPi Camera's Red LEDs
        cueLight = []; %5v power supply for Cue LED
        trialLight = []; %5v power supply for indicating trial start to camera.
        powerPiezo1 = []; %5v power supply GPIO pin for powering the left piezo driver
        %powerPiezo2 = []; %5v power supply GPIO pin for powering the right piezo driver
        piezoDriver1 = []; %analog channel for driving left piezo
        %piezoDriver2 = []; %analog channel for driving right piezo
        reward_given = 0;
        activePump = 'Left'; % var to hold 'left' or 'right' for controlling reward pump functions, default Left
        
        %plot parameters
        lickTimes_left = nan;
        lickTimes_right = nan;
        graspTimes_left = nan;
        graspTimes_right = nan;
        grabStatus = 0; %test flag for dual grasp bars, per trial.
        
        % Stimulus Parameters
        FS = 10000; %sampling rate
        signalPiezo1 = zeros(2,10000); %1 second of signal (Low to HIGH)
        signalPiezo2 = zeros(1,10000); %1 second of signal (HIGH to low)
        stimType = 'N'; % N=none, L=left, R=right
        
        % Session parameters
        sessionType = nan;
        rewardSize = string();
        trialNum = nan;
        numTrials = nan;
        Stim_Reward_Diff = nan;
        ITI_start = nan;
        ITI_end = nan;
        graspTime = nan;
        %curTimeGrasp = nan;
        %prevTimeGrasp = 0;
        out_times = zeros(1,2);
        out_events = strings(1,2);
        T_out = cell(1,10); %output matrix of trial data/metadata
        % (TrialNum,trialStartTime,LickTimes,GraspTimes,StimType,StimOnTime,StimOffTime,RewardSize,RewardTime,TrialEndTime)
        T = 0; % trial start time
        
        % Mouse parameters
        mouseID = 0;
        mouse_pre_weight = 0;
        mouse_post_weight = 0;
        
        % Folder parameters
        outputDirectory = nan;
        expFileLoaded = nan;
        stimLoaded = nan;
        
        
    end
    
    methods (Access = public)
        
        function results = initializeDetectors(app)
            %Set up digital channels (5v out) for powering capacitative sensors
            app.powerCapacitors = daq.createSession('ni');
            addDigitalChannel(app.powerCapacitors,'dev1',['Port0/Line0', 'Port0/Line1', 'Port1/Line2', 'Port1/Line7'], 'OutputOnly');
            % Capacitors in order: (Right Handle, Left Lick Spout, Right Lick Spout, Left Handle)
            
            %set up digital channels (5v out) for controlling left fluid pump
            app.fluidPump = daq.createSession('ni');
            addDigitalChannel(app.fluidPump,'dev1','Port0/Line5:6','OutputOnly');
            
            %set up digital channels (5v out) for controlling right fluid pump
            app.fluidPump2 = daq.createSession('ni');
            addDigitalChannel(app.fluidPump2,'dev1','Port1/Line3:4','OutputOnly');
            
            %set up digital channels for encoding reward size
            app.sizeCodes = daq.createSession('ni');
            addDigitalChannel(app.sizeCodes,'dev1','Port0/Line3:4','OutputOnly');
            
            %set up digital channel for flood light
            app.floodLight = daq.createSession('ni');
            addDigitalChannel(app.floodLight, 'dev1','Port0/Line2','OutputOnly');
            
            %set up digital channel for camera light
            app.cameraLight = daq.createSession('ni');
            addDigitalChannel(app.cameraLight, 'dev1','Port1/Line1','OutputOnly');
            
            %set up digital channel for Cue light
            app.cueLight = daq.createSession('ni');
            addDigitalChannel(app.cueLight, 'dev1','Port1/Line0','OutputOnly');
            
            %set up digital channel for trial light
            app.trialLight = daq.createSession('ni');
            addDigitalChannel(app.trialLight, 'dev1','Port2/Line1','OutputOnly');
            
            %set up digital channel for powering piezo driver 1 (Left / right handle)
            app.powerPiezo1 = daq.createSession('ni');
            addDigitalChannel(app.powerPiezo1, 'dev1',['Port0/Line7','Port2/Line2'],'OutputOnly');
            
            %set up digital channel for powering piezo driver 2 (Right handle)
            %app.powerPiezo2 = daq.createSession('ni');
            %addDigitalChannel(app.powerPiezo2, 'dev1', 'Port2/Line2','OutputOnly');
            
            %Set up analog ouput channels for driving piezos
            app.piezoDriver1 = daq.createSession('ni');
            addAnalogOutputChannel(app.piezoDriver1, 'dev1', [0 1], 'Voltage');
            %             app.piezoDriver2 = daq.createSession('ni'); %channel 2
            %             addAnalogOutputChannel(app.piezoDriver2, 'dev1', 1, 'Voltage');
            app.piezoDriver1.Rate = app.FS; %sampling rate
            %app.piezoDriver2.Rate = app.FS; %sampling rate
            %outputSingleScan(app.powerPiezo1,0); %hold off / low
            %outputSingleScan(app.powerPiezo2,0); %hold off / low
            
            %Setting up detection functions for spouts (Left:AI0 & Right:AI1) and handles (Left:AI2 & Right:AI6)
            app.spout_and_handle_Detection = daq.createSession('ni');
            %addAnalogInputChannel(app.spout_and_handle_Detection,'Dev1', [0 6], 'Voltage'); %spout detection
            addAnalogInputChannel(app.spout_and_handle_Detection,'Dev1', [0 1 2 6], 'Voltage'); %spout detection
            app.spout_and_handle_Detection.Rate = 1000;
            app.spout_and_handle_Detection.IsContinuous = true;
            app.spout_and_handle_Detection.IsNotifyWhenDataAvailableExceedsAuto = true;
            % app.spout_and_handle_Detection.NotifyWhenDataAvailableExceeds = 1000;
            
            results = 'Detectors Initialized';
            
            disp(results);
            
            app.HandleandSpoutDetectorsLamp.Color = [0 1 0];
        end
        
        
        function results = offDetectors(app)
            results = 'Detectors Off';
            
            stop(app.spout_and_handle_Detection);
            %outputSingleScan(app.powerCapacitors,[0,0,0])
            outputSingleScan(app.powerCapacitors,[0,0,0,0])
            outputSingleScan(app.powerPiezo1,[0,0]); %piezo power off
            %outputSingleScan(app.powerPiezo2,0);
            
            app.powerCapacitors = [];
            app.spout_and_handle_Detection = [];
            app.fluidPump = [];
            app.fluidPump2 = [];
            app.sizeCodes = [];
            
            disp(results);
            app.HandleandSpoutDetectorsLamp.Color = [1 0 0];
            
            %             plot(app.rasterResponse_axes,randi([1 10], 1000,1),'-r');
            %             hold(app.rasterResponse_axes);
            %             plot(app.rasterResponse_axes,randi([1 10], 1000,1),'-b');
            %             hold(app.rasterResponse_axes);
        end
        
        %function spout_detection2(src,evt)
        function spout_detection2(app, ~, event)
            
            chData = []; chData2 = []; chTemp = [];
            checkSpout = []; checkHandle = [];
            
            
            %extracting data from NI
            chData = event.Data(:,1);
            chData2 = event.Data(:,2);
            chData3 = event.Data(:,3);
            chData4 = event.Data(:,4);
            
            chTemp = event.Data(:,3);
            chTemp2 = event.Data(:,4);
            
            %determining changes in signals
            chData = abs(diff(chData));
            chData2 = abs(diff(chData2));
            chData3 = abs(diff(chData3));
            chData4 = abs(diff(chData4));
            
            %getting the timing of events
            t = event.TimeStamps(1:length(chData),1);
            
            %------------------------
            %checking spout licking
            %Animal licked the left spout or not
            
            checkSpoutLeft = find(chData > 2);
            if ~isempty(checkSpoutLeft)
                app.total_licks_left = app.total_licks_left + 1;
                app.lickTimes_left = cat(1,app.lickTimes_left,GetSecs);
                
                if app.total_licks_left <= 100
                    app.T_out{app.trialNum,3}(app.total_licks_left) = GetSecs - app.T; %add time data to each lick
                else
                    app.T_out{app.trialNum,3} = [app.T_out{app.trialNum,3} GetSecs - app.T]; %over 100 events, append new data
                end
            else
                app.spoutLicked_left = 0;
            end
            
            checkSpoutRight = find(chData2 > 2);
            if ~isempty(checkSpoutRight)
                app.total_licks_right = app.total_licks_right + 1;
                app.lickTimes_right = cat(1,app.lickTimes_right,GetSecs);
                
                if app.total_licks_right <= 100
                    app.T_out{app.trialNum,11}(app.total_licks_right) = GetSecs - app.T; %add time data to each lick
                else
                    app.T_out{app.trialNum,11} = [app.T_out{app.trialNum,11} GetSecs - app.T]; %over 100 events, append new data
                end
            else
                app.spoutLicked_right = 0;
            end
            
            %------------------------
            %Animal grabed the handle or not
            checkHandleLeft = find(chData3 > 2); %left handle
            if ~isempty(checkHandleLeft)
                app.handleGrasped_left = 1;
                app.total_grasps_left = app.total_grasps_left + 1;
                app.graspTimes_left = cat(1,app.graspTimes_left,GetSecs);
                if app.total_grasps_left <= 100
                    app.T_out{app.trialNum,4}(app.total_grasps_left) = GetSecs-app.T; %add time data to each L_grasp
                else
                    app.T_out{app.trialNum,4} = [app.T_out{app.trialNum,4} GetSecs-app.T]; %over 100 events, append new data
                end
            else
                app.handleGrasped_left = 0;
            end
            
            checkHandleRight = find(chData4 > 2); %right handle
            if ~isempty(checkHandleRight)
                app.handleGrasped_right = 1;
                app.total_grasps_right = app.total_grasps_right + 1;
                app.graspTimes_right = cat(1,app.graspTimes_right,GetSecs);
                if app.total_grasps_right <= 100
                    app.T_out{app.trialNum,12}(app.total_grasps_right) = GetSecs-app.T; %add time data to each R_grasp
                else
                    app.T_out{app.trialNum,12} = [app.T_out{app.trialNum,12} GetSecs-app.T]; %over 100 events, append new data
                end
            else
                app.handleGrasped_right = 0;
            end
            %---------------------------------
            
            %             if  ~isempty(checkHandleLeft)
            %                 app.handleGrasped_left = 1;
            %                 %app.curTimeGrasp = GetSecs;
            %             else
            %                 app.handleGrasped_left = 0;
            %             end
            %
            %             if  ~isempty(checkHandleRight)
            %                 app.handleGrasped_right = 1;
            %                 %app.curTimeGrasp = GetSecs;
            %             else
            %                 app.handleGrasped_right = 0;
            %             end
            
            chTemp = find(chTemp > -9);
            chTemp2 = find(chTemp2 > -9);
            
            if length(chTemp) > 90  && length(chTemp2) > 90 % both grasping grasps
                if app.sessionType == 1 %Association with tactile stimulus session
                    if app.grabStatus == 0
                        disp('holding both bars.');
                        app.grabStatus = 1; %flag for per trial grabs
                        handle_stim_main(app); %start stim
                    else
                    end
                elseif app.sessionType == 2 %Grasping training session
                    if app.reward_given == 0
                        deliver_reward(app);
                    else
                        %reward delivered for this trial already...
                    end
                else
                end
                
            elseif length(chTemp) > 90 %left grasp)
                disp('Left Bar Only...');
                %app.T_out{app.trialNum,4}(app.total_grasps) = GetSecs - T; %record grasp time
            elseif length(chTemp2) > 90 %right grasp
                disp('Right Bar Only...');
                %app.T_out{app.trialNum,12}(app.total_grasps) = GetSecs - T; %record grasp time
            else
            end
        end
    end
    
    methods (Access = private)
        
        function results = init_session_parameters(app)
            
            if ~isempty(app.powerCapacitors)
            else
                errordlg('Initiliaze detectors', 'modal');
                return
            end
            
            %Parameters common to all sessions
            %app.mouseID = get(app.MouseID_editBox, 'value');
            if isempty(app.mouseID)
                app.mouseID = nan;
                errordlg('Enter a Mouse ID', 'modal');
                return
            else
            end
            
            %app.mouse_pre_weight = get(app.MousepreWeight_editBox, 'value');
            %app.mouse_post_weight = get(app.MousepostWeight_editBox, 'value');
            %app.outputDirectory = get(app.SaveDirLabel, 'text');
            
            if isempty(app.outputDirectory)
                app.outputDirectory = nan;
                errordlg('Enter a directory to save files', 'modal');
                return
            else
            end
            
            app.rewardSize = get(app.RewardSizeuLEditField, 'value');
            
            %----------------
            
            %determining with session to run
            if app.AssociationwithTactileStimButton.Value == 1
                app.sessionType = 1;
                app.Stim_Reward_Diff = get(app.StimtoRewardTimeDiffmsEditField, 'value');
                
            elseif app.GraspingTrainingButton.Value == 1
                app.sessionType = 2;
                app.graspTime = get(app.GraspTimeOutEditField, 'value');
                
            elseif app.DetectionButton.Value == 1
                app.sessionType = 3;
                
                if sum(abs(app.ExperimentalFileLamp.Color - [0 1 0])) == 0
                    app.expFileLoaded = get(app.ExperimentalFileLabel, 'text');
                    if isempty(app.expFileLoaded)
                        app.ExperimentalFileLamp.Color = [1 0 0];
                        app.expFileLoaded = nan;
                        errordlg('Load an Experimental file', 'modal');
                        return
                    else
                    end
                else
                    app.ExperimentalFileLamp.Color = [1 0 0];
                    app.expFileLoaded = nan;
                    errordlg('Load Experimental File', 'modal');
                    return
                end
                
            elseif app.RandomRewardButton.Value == 1
                app.sessionType = 4;
                
            else
                app.sessionType = nan;
            end
            
            if (app.sessionType == 1 | app.sessionType == 2 | app.sessionType == 4)
                app.numTrials = get(app.NumberoftrialsEditField, 'value');
                app.ITI_start = get(app.ITIStartmsEditField, 'value');
                app.ITI_end = get(app.ITIEndmsEditField, 'value');
            else
            end
            
            app.sessionStart = 1;
            
        end
        
        function results = deliver_reward(app)
            % moved deliver reward from out of the spout_detection_v2_code
            if app.sessionType == 1 %Association with tactile stimulus session
                %Reward a particular side, based on Stim set
                if  app.stimType == "L"
                    %left spout
                    outputSingleScan(app.fluidPump,[1,0]);
                    WaitSecs(.005);
                    outputSingleScan(app.fluidPump,[0,0]);
                elseif app.stimType == "R"
                    %right spout
                    outputSingleScan(app.fluidPump2,[1,0]);
                    WaitSecs(.005);
                    outputSingleScan(app.fluidPump2,[0,0]);
                else
                    error("Stim Type Not Updated or Recognized! in deliver_reward()");
                end
                disp('Reward Delivered.');
                app.T_out(app.trialNum,9) = {GetSecs - app.T}; %record reward time
                app.T_out(app.trialNum,8) = {app.rewardSize}; %record reward size
                app.reward_given = 1;
                % cue led ON
                outputSingleScan(app.cueLight, 1);
            elseif app.sessionType == 2 %Grasping training session
                %random reward
                if  rand() < 0.5
                    %left spout
                    outputSingleScan(app.fluidPump,[1,0]);
                    WaitSecs(.005);
                    outputSingleScan(app.fluidPump,[0,0]);
                else
                    %right spout
                    outputSingleScan(app.fluidPump2,[1,0]);
                    WaitSecs(.005);
                    outputSingleScan(app.fluidPump2,[0,0]);
                end
                disp('Reward Delivered.');
                app.T_out(app.trialNum,9) = {GetSecs - app.T}; %record reward time
                app.T_out(app.trialNum,8) = {app.rewardSize}; %record reward size
                app.reward_given = 1;
                % cue led ON
                outputSingleScan(app.cueLight, 1);
            end
        end
        
        function results = deliver_stim(app)
            outputSingleScan(app.powerPiezo1,[1,1]); %power ON piezo
            outputSingleScan(app.trialLight, 1); %indicator light for visualizing stimulus
            if app.stimType == "R"
                app.piezoDriver1.queueOutputData(app.signalPiezo1'); %que stim data
            elseif app.stimType == "L"
                app.piezoDriver1.queueOutputData(app.signalPiezo2'); %que stim data
            else
                error("Stim Type Not Updated or Recognized! in deliver_stim()");
            end
            app.T_out(app.trialNum,6) = {GetSecs - app.T}; %record stim ON time
            app.piezoDriver1.startForeground(); %start stim delivery
            outputSingleScan(app.powerPiezo1,[0,0]); %piezo power off
            app.T_out(app.trialNum,7) = {GetSecs - app.T}; %record stim OFF time
            outputSingleScan(app.trialLight, 0); %indicator light for visualizing stimulus
        end
        
        function results = handle_stim_main(app)
            %disp("handle_stim_main called!");
            startTimer = GetSecs;
            
            while (GetSecs - startTimer) <= 0.5
                %maybe add a waitsecs (2ms)
            end
            
            if rand() < 1 %randomly interleave some trials with stim
                %Pick a stimulus type
                if rand() < 0.5
                    app.stimType = "L";
                else
                    app.stimType = "R";
                end
                app.T_out(app.trialNum,5) = {app.stimType}; %record stim type
                
                %provide stimulus
                deliver_stim(app);
                disp('stimulus presented');
            end
            
            %provide reward
            deliver_reward(app);
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: HandleandSpoutDetectorsSwitch
        function HandleandSpoutDetectorsSwitchValueChanged(app, event)
            value = app.HandleandSpoutDetectorsSwitch.Value;
            if strcmpi(value, 'on')
                initializeDetectors(app);
            else
                offDetectors(app);
            end
        end

        % Value changed function: StartExperiment_Button
        function StartExperiment_ButtonValueChanged(app, event)
            
            app.experiment_started = 1;
            app.trialNum = 1;
            app.T_out = cell(get(app.NumberoftrialsEditField, 'value'),11);
            % (TrialNum,trialStartTime,LickTimesL,GraspTimesL,StimType,StimOnTime,StimOffTime,RewardSize,RewardTime,TrialEndTime,LickTimesR, GraspTimesR)
            app.T_out(:,3) = {zeros(1,100)}; %Lick times Left
            app.T_out(:,4) = {zeros(1,100)}; %Grasp Times Left
            app.T_out(:,11) = {zeros(1,100)}; %Lick Times Right
            app.T_out(:,12) = {zeros(1,100)}; %Grasp Times Right
            
            
            app.T = GetSecs; %start Timer
            
            %----------
            %SEND PULSE TO R-PI to synch both clocks
            %---------
            
            app.sessionStart = 0;
            init_session_parameters(app);
            
            detectionTask = nan;
            disp(app.trialNum);
            
            if app.sessionStart == 1
                
                totalTrials = nan;
                
                if app.sessionType == 1 %Association with tactile stimulus session
                    totalTrials = app.numTrials;
                    app.grabStatus = 0; %reset flag
                    
                elseif app.sessionType == 2 %Grasping training session
                    totalTrials = app.numTrials;
                    
                elseif app.sessionType == 3 %Detection session
                    
                    %load experimental file
                    totalTrials = [];
                    detectionTask = 1;
                    
                elseif app.sessionType == 4 %Random Reward session
                    totalTrials = app.numTrials;
                    
                else
                    errordlg('Weird State ... Re-check all parameters', 'modal');
                    return
                end
                
                app.StartExperiment_Button.BackgroundColor = [0 1 0];
                
                %Power and inititate daq devices
                disp('Powering capacitors');
                outputSingleScan(app.powerCapacitors,[1,1,1,1]); %power P0.0 (handle), P0.1 (Left spout) and P1.2 (Right spout)
                %                 outputSingleScan(app.powerCapacitors1, 1); %power P1.2 (Right spout)
                WaitSecs(.1);
                disp('Starting listener');
                app.lh = addlistener(app.spout_and_handle_Detection,'DataAvailable', ...
                    @(src,event) spout_detection2(app, src, event));
                WaitSecs(.1);
                disp('Starting collection of data in the background');
                startBackground(app.spout_and_handle_Detection);
                app.startExperiment = GetSecs;
                
                cla(app.rasterResponse_axes);
                
                %main trial loop
                WaitSecs(2);
                
                for nn = 1 : totalTrials
                    
                    %send Raspberry video trigger to log time point of trial start
                    %---------
                    %MATLAB sends trigger to rasp pi (TTL pulse)
                    %rasp pi checks for line high (or low)
                    %if line is high, then record time and send to MATLAB for logging into MAT-FILE behavior (T-OUT)
                    %----------
                    
                    outputSingleScan(app.cueLight, 0); %cue light off
                    
                    %clear test dual grasp flag
                    app.grabStatus = 0;
                    
                    app.lickTimes_left = [];
                    app.lickTimes_right = [];
                    
                    app.T_out(nn,1) = {nn}; %record trial number
                    app.T_out(nn,2) = {GetSecs - app.T};%record trial start time
                    
                    app.total_grasps_right = 0; % clear counter
                    app.total_grasps_left = 0;
                    app.total_licks_left = 0; %clear counter
                    app.total_licks_right = 0; %clear counter
                    app.reward_given = 0;
                    
                    if app.sessionStart == 0
                        break;
                    end
                    
                    disp(['Trial Number: ' num2str(nn)]);
                    
                    iti_start = []; iti_end = [];
                    
                    app.startTrialTime = GetSecs;
                    
                    if isnan(detectionTask)
                        
                        iti_start = app.ITI_start; iti_end = app.ITI_end;
                        timeRewardToStim = nan;
                        pickITI = randi([iti_start iti_end],1,1);
                        
                        if app.sessionType == 4 %random reward session
                            %deliver reward
                            disp('reward given');
                            app.T_out(nn,9) = {GetSecs - app.T}; %record reward time
                            app.T_out(nn,8) = {app.rewardSize}; %record reward size
                            outputSingleScan(app.fluidPump,[1,0]);
                            WaitSecs(.005);
                            outputSingleScan(app.fluidPump,[0,0]);
                            
                        elseif app.sessionType == 2 %grasping training session
                            outputSingleScan(app.cueLight, 0); %cue light off
                            
                        elseif app.sessionType == 1 %tactile association session
                            timeRewardToStim = app.Stim_Reward_Diff;
                            startTimerReward = GetSecs;
                            if app.grabStatus == 1
                                if timeRewardToStim < 0
                                    %provide reward
                                    deliver_reward(app);
                                    %wait
                                    while (GetSecs - startTimerReward) <= abs(timeRewardToStim)
                                        %WaitSecs(0.005);
                                    end
                                    %provide stimulus
                                    disp('stimulus presented');
                                    app.T_out(nn,5) = {'stim_type'}; %record stim type
                                    app.T_out(nn,6) = {GetSecs - app.T}; %record stim ON time
                                    WaitSecs(0.5); %give stim here
                                    app.T_out(nn,7) = {GetSecs - app.T}; %stim off time
                                    
                                elseif timeRewardToStim < 1000
                                    %THIS ONE IS ALWAYS CALLED <@mduhain Jun 9 2021>
                                    % SEE LINE 341
                                    
                                    %                                     %WaitSecs for 0.5 sec
                                    %                                     WaitSecs(0.5);
                                    %
                                    %                                     %provide stimulus
                                    %                                     deliver_stim(app);
                                    %                                     disp('stimulus presented');
                                    %                                     app.T_out(nn,5) = {'stim_type'}; %record stim type
                                    %                                     app.T_out(nn,6) = {GetSecs - app.T}; %record stim ON time
                                    %                                     WaitSecs(0.5); %give stim here
                                    %                                     app.T_out(nn,7) = {GetSecs - app.T}; %stim off time
                                    %
                                    %                                     %wait
                                    %                                     WaitSecs(0.5);
                                    %
                                    %                                     %provide reward
                                    %                                     deliver_reward(app);
                                    
                                else
                                    %provide stimulus
                                    disp('stimulus presented');
                                    app.T_out(nn,5) = {'stim_type'}; %record stim type
                                    app.T_out(nn,6) = {GetSecs - app.T}; %record stim ON time
                                    WaitSecs(0.5); %give stim here
                                    app.T_out(nn,7) = {GetSecs - app.T}; %stim off time
                                    %provide reward
                                    deliver_reward(app);
                                    app.T_out(nn,9) = {GetSecs - app.T}; %record reward time
                                    app.T_out(nn,8) = {app.rewardSize}; %record reward size
                                    
                                end
                            end
                        else
                        end
                        
                    else
                    end
                    
                    pickITI = randi([iti_start iti_end],1,1)/1000;
                    while (GetSecs - app.startTrialTime) <= pickITI
                        if app.sessionType == 2
                            if app.handleGrasped_left == 1
                                
                                %                                 if isnan(app.prevTimeGrasp)
                                %                                     disp('deliver reward for grasping');
                                %                                     app.prevTimeGrasp = app.curTimeGrasp;
                                %                                 else
                                %                                     if (app.curTimeGrasp - app.prevTimeGrasp) > (app.graspTime/1000)
                                %                                         disp('deliver reward for grasping');
                                %                                         app.prevTimeGrasp = app.curTimeGrasp;
                                %                                     else
                                %                                     end
                                %                                 end
                                
                            else
                            end
                        else
                        end
                        
                        
                        %                         %if left spout licking was detected
                        %                         if ~isempty(app.lickTimes_left)
                        %                             lickTimesRaster = nan;
                        %                             lickTimes2 = round((app.lickTimes_left - app.startTrialTime) * 1000);
                        %                             plot(app.rasterResponse_axes, lickTimes2, zeros(length(lickTimes2),1) + nn, '.b');
                        %                             hold(app.rasterResponse_axes,'on');
                        %                             axis(app.rasterResponse_axes, [-5000 5000 0 100]);
                        %                         else
                        %                         end
                        %
                        %                         %if right spout licking was detected
                        %                         if ~isempty(app.lickTimes_right)
                        %                             lickTimesRaster = nan;
                        %                             lickTimes2 = round((app.lickTimes_right - app.startTrialTime) * 1000);
                        %                             plot(app.rasterResponse_axes, lickTimes2, zeros(length(lickTimes2),1) + nn, '.r');
                        %                             hold(app.rasterResponse_axes,'on');
                        %                             axis(app.rasterResponse_axes, [-5000 5000 0 100]);
                        %                         else
                        %                         end
                        
                        WaitSecs(0.005);
                    end
                    
                    % %                     if left spout licking was detected
                    %                     if ~isempty(app.lickTimes_left)
                    %                         lickTimes2 = round((app.lickTimes_left - app.startTrialTime) * 1000);
                    %                         hold(app.rasterResponse_axes,'on');
                    %                         plot(app.rasterResponse_axes, lickTimes2, zeros(length(lickTimes2),1) + nn, '.b');
                    %                         plot(app.rasterResponse_axes, app.T_out(nn,6), zeros(length(lickTimes2),1) + nn, 'ok', 'linewidth', 3);
                    %                         axis(app.rasterResponse_axes, [-5000 5000 0 100]);
                    %                     else
                    %                     end
                    %
                    %                     %if right spout licking was detected
                    %                     if ~isempty(app.lickTimes_right)
                    %                         lickTimes2 = round((app.lickTimes_right - app.startTrialTime) * 1000);
                    %                         hold(app.rasterResponse_axes,'on');
                    %                         plot(app.rasterResponse_axes, lickTimes2, zeros(length(lickTimes2),1) + nn, '.r');
                    %                         plot(app.rasterResponse_axes, app.T_out(nn,6), zeros(length(lickTimes2),1) + nn, 'ok', 'linewidth', 3);
                    %                         axis(app.rasterResponse_axes, [-5000 5000 0 100]);
                    %                     else
                    %                     end
                    %                     WaitSecs(0.05);
                    
                    app.trialNum = app.trialNum + 1;
                    app.T_out{nn,10} = GetSecs - app.T; %record trial END time
                end %end trial loop nn
                
                disp('end block of trials')
                app.out_times = [app.out_times GetSecs - app.T];
                app.out_events = [app.out_events "End_Of_Trials"];
                
            else
                try
                    stop(app.spout_and_handle_Detection);
                catch
                end
                app.StartExperiment_Button.Value = 0;
                app.StartExperiment_Button.BackgroundColor = [.96 .96 .96];
            end
            try
                stop(app.spout_and_handle_Detection);
            catch
            end
            
            %Outport the saved data (out_times and out_events)
            disp('Saving Experiment data...')
            working_dir = pwd; %current directory pre-save
            
            %find correct folder to save data into
            try
                cd(app.outputDirectory); %should be specified earlier in GUI.
            catch
                disp('No mouse directory specified - saving file to Desktop...');
                app.outputDirectory = 'C:\Users\ramirezlab\Desktop';
                cd(app.outputDirectory);
            end
            
            % TODO create unique file name for mouse (m235_2021_01_29_001) Mouse#_Year_Month_Day_Exp#
            currDate = strrep(datestr(datetime), ':', '_');
            exp_name = strcat('m', num2str(app.mouseID),'_',currDate,'.mat');
            %exp_name = 'Test_File_Outport.mat';
            Table_out = app.T_out;
            save(exp_name,'Table_out');
            WaitSecs(0.1);
            disp('Experiment data saved!')
            
            
            %TODO save a metadata file along with the experiment file (m235_2021_001_29_001_meta)
            % mouse weight pre.
            % mouse weight post .
            
            app.StartExperiment_Button.Value = 0;
            app.StartExperiment_Button.BackgroundColor = [.96 .96 .96];
            
            app.experiment_started = 0; %turn off flag for stop button.
            
        end

        % Button pushed function: DeliverRewardButton
        function DeliverRewardButtonPushed(app, event)
            % deliver the reward to the animal
            % through Digital channel P0.6 (pin16)
            disp('Reward Delivered.');
            if  strcmp(app.activePump, 'Left')
                outputSingleScan(app.fluidPump,[1,0]);
                WaitSecs(.1);
                outputSingleScan(app.fluidPump,[0,0]);
            elseif  strcmp(app.activePump, 'Right')
                outputSingleScan(app.fluidPump2,[1,0]);
                WaitSecs(.1);
                outputSingleScan(app.fluidPump2,[0,0]);
            else
                disp('ERROR: No Active Pump');
            end
            
            
        end

        % Value changed function: PushoutLiquid_Button
        function PushoutLiquid_ButtonValueChanged(app, event)
            % function for continuous flush of the fluid pump
            % send a 5v step until the button is un-selected
            value = app.PushoutLiquid_Button.Value;
            if value == 1
                if strcmp(app.activePump, 'Left')
                    disp('Left Pump Extruding')
                    outputSingleScan(app.fluidPump, [1,0]);
                elseif strcmp(app.activePump, 'Right')
                    disp('Right Pump Extruding')
                    outputSingleScan(app.fluidPump2, [1,0]);
                else
                    error('No Active Pump!');
                end
            else
                disp('Pump Stopped')
                outputSingleScan(app.fluidPump, [0,0]);
                outputSingleScan(app.fluidPump2, [0,0]);
            end
        end

        % Value changed function: SuckInLiquid_Button
        function SuckInLiquid_ButtonValueChanged(app, event)
            value = app.SuckInLiquid_Button.Value;
            % function for continuous retracting of the fluid pump
            % send a 5v step until the button is un-selected
            if value == 1
                if strcmp(app.activePump, 'Left')
                    disp('Left Pump Retracting')
                    outputSingleScan(app.fluidPump, [0,1]);
                elseif strcmp(app.activePump, 'Right')
                    disp('Right Pump Retracting')
                    outputSingleScan(app.fluidPump2, [0,1]);
                else
                    error('No Active Pump!');
                end
            else
                disp('Pump Stopped')
                outputSingleScan(app.fluidPump, [0,0]);
                outputSingleScan(app.fluidPump2, [0,0]);
            end
        end

        % Callback function
        function MouseID_editBoxValueChanged(app, event)
            %NOT USED
        end

        % Value changed function: MouseIDEditField
        function MouseIDEditFieldValueChanged(app, event)
            value = app.MouseIDEditField.Value;
            % input value (numeric) for mouse ID number
            disp(strcat("Welcome! Mouse ", num2str(value)));
            app.mouseID = value;
            
            % Change current directory to this Mouse's Folder
            
            % Save experiment data to this location
            
            % Figure out function logic for if the user inputs mouse number or mouse weight first.
        end

        % Value changed function: PreWeightgEditField
        function PreWeightgEditFieldValueChanged(app, event)
            value = app.PreWeightgEditField.Value;
            app.mouse_pre_weight = value;
            mouseNum = app.MouseIDEditField.Value;
            disp(strcat("Mouse ",num2str(mouseNum)," weighs ",num2str(value),"g."));
        end

        % Value changed function: RewardSizeuLEditField
        function RewardSizeuLEditFieldValueChanged(app, event)
            value = app.RewardSizeuLEditField.Value;
            % A new value has been assigned to the Reward Size parameter.
            disp(strcat("New reward size: ",num2str(value),"uL"));
            % Send update code to arduino
            outputSingleScan(app.fluidPump, [1,1]);
            if value <= 5
                %low reward size
                disp('Small reward.');
                app.rewardSize = 'small 0-5uL';
                outputSingleScan(app.sizeCodes, [0,0]);
            elseif value > 5 && value <= 10
                %med reward size
                disp('Medium reward.');
                app.rewardSize = 'medium 5-10uL';
                outputSingleScan(app.sizeCodes, [1,0]);
                WaitSecs(.1);
                outputSingleScan(app.sizeCodes, [0,0]);
            elseif value > 10 && value <= 15
                %large reward
                disp("Large reward.");
                app.rewardSize = 'large 10-15uL';
                outputSingleScan(app.sizeCodes, [0,1]);
                WaitSecs(.1);
                outputSingleScan(app.sizeCodes, [0,0]);
            elseif value > 15
                %extra-large reward
                disp("Massive reward.");
                app.rewardSize = 'massive 15-20uL';
                outputSingleScan(app.sizeCodes, [1,1]);
                WaitSecs(.1);
                outputSingleScan(app.sizeCodes, [0,0]);
            else
                error('Reward Size Not Possible');
            end
            outputSingleScan(app.fluidPump, [0,0]);
            
            
        end

        % Button pushed function: DirectorytoSaveButton
        function DirectorytoSaveButtonPushed(app, event)
            % Get user input for which directory to save the data into
            dir_name = uigetdir('C:\Users\ramirezlab\Desktop\Mouse_Data\');
            disp(dir_name);
            app.outputDirectory = dir_name;
            % update the current directory to save the data into.
        end

        % Button pushed function: StopExperimentButton
        function StopExperimentButtonPushed(app, event)
            flag = app.experiment_started;
            if flag == 1
                % Basically UNDO anyting that the start experiemnt button did.
                app.sessionStart = 0; %halts current trial
                % SAVE DATA
                disp('Saving Experiment data...');
                %Outport the saved data (out_times and out_events)
                working_dir = pwd; %current directory pre-save
                %find correct folder to save data into
                try
                    cd(app.outputDirectory); %should be specified earlier in GUI.
                catch
                    disp('No mouse directory specified - saving file to Desktop...');
                    app.outputDirectory = 'C:\Users\ramirezlab\Desktop';
                    cd(app.outputDirectory);
                end
                %disp(app.out_events(1));
                currDate = strrep(datestr(datetime), ':', '_');
                exp_name = strcat('m', num2str(app.mouseID),'_',currDate,'.mat');
                %exp_name = 'Test_File_Outport.mat';
                Table_out = app.T_out;
                save(exp_name,'Table_out');
                disp('Experiment data saved!')
            else
                disp("No experiment to stop...");
            end
            
            
        end

        % Value changed function: OverheadLightsSwitch
        function OverheadLightsSwitchValueChanged(app, event)
            value = app.OverheadLightsSwitch.Value;
            % Turn overhead lights ON and OFF
            % P0.2 for power
            value = app.OverheadLightsSwitch.Value;
            if strcmp(value,'On')
                outputSingleScan(app.floodLight, 1);
            elseif strcmp(value, 'Off')
                outputSingleScan(app.floodLight, 0);
            end
        end

        % Value changed function: NumberoftrialsEditField
        function NumberoftrialsEditFieldValueChanged(app, event)
            value = app.NumberoftrialsEditField.Value;
            disp(strcat("New number of trials is: ",num2str(value)));
        end

        % Value changed function: CameraLightsSwitch
        function CameraLightsSwitchValueChanged(app, event)
            value = app.CameraLightsSwitch.Value;
            % Turn RPi camera lights ON and OFF
            % P1.1 for power
            value = app.CameraLightsSwitch.Value;
            if strcmp(value,'On')
                outputSingleScan(app.cameraLight, 1);
            elseif strcmp(value, 'Off')
                outputSingleScan(app.cameraLight, 0);
            end
        end

        % Value changed function: CueLightSwitch
        function CueLightSwitchValueChanged(app, event)
            value = app.CueLightSwitch.Value;
            % Turn front cue light ON and OFF
            % P1.0 for power
            value = app.CueLightSwitch.Value;
            if strcmp(value,'On')
                outputSingleScan(app.cueLight, 1);
            elseif strcmp(value, 'Off')
                outputSingleScan(app.cueLight, 0);
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            disp(strcat("active pump: ", string(value)));
            app.activePump = value;
        end

        % Button pushed function: LoadStimulusFileButton
        function LoadStimulusFileButtonPushed(app, event)
            % Create the piezo stimulus file
            % TODO: move these variables up into global app.variables
            freq1 = 20;
            freq2 = 40;
            dur = 0.5;
            amp = -3;
            t = 0:1/app.FS:dur - 1/app.FS;
            %low to HIGH (LICK RIGHT)
            app.signalPiezo1(1,1:length(t)) = amp * sin(2*pi*t*freq1);
            app.signalPiezo1(1,length(t)+1:end) = amp * sin(2*pi*t*freq2);
            app.signalPiezo1(2,1:length(t)) = amp * sin(2*pi*t*freq1);
            app.signalPiezo1(2,length(t)+1:end) = amp * sin(2*pi*t*freq2);
            
            %HIGH to low (LICK LEFT)
            app.signalPiezo2(1,1:length(t)) = amp * sin(2*pi*t*freq2);
            app.signalPiezo2(1,length(t)+1:end) = amp * sin(2*pi*t*freq1);
            app.signalPiezo2(2,1:length(t)) = amp * sin(2*pi*t*freq2);
            app.signalPiezo2(2,length(t)+1:end) = amp * sin(2*pi*t*freq1);
            
            
            disp("Stimulus file loaded successfully...")
            app.LoadStimulusFileLamp.Color = [0 1 0];
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.rasterResponse_axes = uiaxes(app.UIFigure);
            title(app.rasterResponse_axes, 'Response Raster')
            xlabel(app.rasterResponse_axes, 'X')
            ylabel(app.rasterResponse_axes, 'Y')
            app.StartExperiment_Button = uibutton(app.UIFigure, 'state');
            app.StartExperiment_Button.ValueChangedFcn = createCallbackFcn(app, @StartExperiment_ButtonValueChanged, true);
            app.StartExperiment_Button.Text = 'Start Experiment';
            app.handleGrasp_axes = uiaxes(app.UIFigure);
            title(app.handleGrasp_axes, 'Handle Grasping')
            xlabel(app.handleGrasp_axes, 'X')
            ylabel(app.handleGrasp_axes, 'Y')
            app.SessionTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.SessionTypeButtonGroup.Title = 'Session Type';
            app.AssociationwithTactileStimButton = uiradiobutton(app.SessionTypeButtonGroup);
            app.AssociationwithTactileStimButton.Text = 'Association with Tactile Stim';
            app.AssociationwithTactileStimButton.Value = true;
            app.GraspingTrainingButton = uiradiobutton(app.SessionTypeButtonGroup);
            app.GraspingTrainingButton.Text = 'Grasping Training';
            app.DetectionButton = uiradiobutton(app.SessionTypeButtonGroup);
            app.DetectionButton.Text = 'Detection';
            app.RandomRewardButton = uiradiobutton(app.SessionTypeButtonGroup);
            app.RandomRewardButton.Text = 'Random Reward';
            app.HandleandSpoutDetectorsSwitch = uiswitch(app.UIFigure, 'slider');
            app.HandleandSpoutDetectorsSwitch.ValueChangedFcn = createCallbackFcn(app, @HandleandSpoutDetectorsSwitchValueChanged, true);
            app.InitializeDetectorsLabel = uilabel(app.UIFigure);
            app.InitializeDetectorsLabel.Text = 'Initialize Detectors';
            app.HandleandSpoutDetectorsLamp = uilamp(app.UIFigure);
            app.DirectorytoSaveButton = uibutton(app.UIFigure, 'push');
            app.DirectorytoSaveButton.ButtonPushedFcn = createCallbackFcn(app, @DirectorytoSaveButtonPushed, true);
            app.DirectorytoSaveButton.Text = 'Directory to Save';
            app.SaveDirLabel = uilabel(app.UIFigure);
            app.SaveDirLabel.Text = 'N/A';
            app.LoadExperimentalFileButton = uibutton(app.UIFigure, 'push');
            app.LoadExperimentalFileButton.Text = 'Load Experimental File';
            app.NotLoadedLabel = uilabel(app.UIFigure);
            app.NotLoadedLabel.Text = 'Not Loaded';
            app.ExperimentalFileLamp = uilamp(app.UIFigure);
            app.ParametersforRandomRewardAssociationTrainingSessionsPanel = uipanel(app.UIFigure);
            app.ParametersforRandomRewardAssociationTrainingSessionsPanel.Title = 'Parameters for Random Reward & Association Training Sessions';
            app.StimtoRewardTimeDiffmsEditFieldLabel = uilabel(app.ParametersforRandomRewardAssociationTrainingSessionsPanel);
            app.StimtoRewardTimeDiffmsEditFieldLabel.Text = 'Stim to Reward Time Diff. (ms)';
            app.StimtoRewardTimeDiffmsEditField = uieditfield(app.ParametersforRandomRewardAssociationTrainingSessionsPanel, 'numeric');
            app.StimtoRewardTimeDiffmsEditField.Value = 500;
            app.LoadStimulusFileButton = uibutton(app.ParametersforRandomRewardAssociationTrainingSessionsPanel, 'push');
            app.LoadStimulusFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStimulusFileButtonPushed, true);
            app.LoadStimulusFileButton.Text = 'Load Stimulus File';
            app.LoadStimulusFileLamp = uilamp(app.ParametersforRandomRewardAssociationTrainingSessionsPanel);
            app.NumberoftrialsEditFieldLabel = uilabel(app.ParametersforRandomRewardAssociationTrainingSessionsPanel);
            app.NumberoftrialsEditFieldLabel.Text = 'Number of trials';
            app.NumberoftrialsEditField = uieditfield(app.ParametersforRandomRewardAssociationTrainingSessionsPanel, 'numeric');
            app.NumberoftrialsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberoftrialsEditFieldValueChanged, true);
            app.NumberoftrialsEditField.Value = 100;
            app.StimulusfileLabel = uilabel(app.ParametersforRandomRewardAssociationTrainingSessionsPanel);
            app.StimulusfileLabel.Text = 'Stimulus file';
            app.ITIStartmsEditField_2Label = uilabel(app.ParametersforRandomRewardAssociationTrainingSessionsPanel);
            app.ITIStartmsEditField_2Label.Text = 'ITI Start (ms)';
            app.ITIStartmsEditField = uieditfield(app.ParametersforRandomRewardAssociationTrainingSessionsPanel, 'numeric');
            app.ITIStartmsEditField.Value = 5000;
            app.ITIEndmsLabel = uilabel(app.ParametersforRandomRewardAssociationTrainingSessionsPanel);
            app.ITIEndmsLabel.Text = 'ITI End (ms)';
            app.ITIEndmsEditField = uieditfield(app.ParametersforRandomRewardAssociationTrainingSessionsPanel, 'numeric');
            app.ITIEndmsEditField.Value = 10000;
            app.GrasptimeoutmsLabel = uilabel(app.ParametersforRandomRewardAssociationTrainingSessionsPanel);
            app.GrasptimeoutmsLabel.Text = 'Grasp timeout (ms)';
            app.GraspTimeOutEditField = uieditfield(app.ParametersforRandomRewardAssociationTrainingSessionsPanel, 'numeric');
            app.GraspTimeOutEditField.Value = 1000;
            app.RewardParametersPanel = uipanel(app.UIFigure);
            app.RewardParametersPanel.Title = 'Reward Parameters';
            app.RewardSizeuLEditFieldLabel = uilabel(app.RewardParametersPanel);
            app.RewardSizeuLEditFieldLabel.Text = 'Reward Size (uL)';
            app.RewardSizeuLEditField = uieditfield(app.RewardParametersPanel, 'numeric');
            app.RewardSizeuLEditField.ValueChangedFcn = createCallbackFcn(app, @RewardSizeuLEditFieldValueChanged, true);
            app.RewardSizeuLEditField.Value = 5;
            app.SuckInLiquid_Button = uibutton(app.RewardParametersPanel, 'state');
            app.SuckInLiquid_Button.ValueChangedFcn = createCallbackFcn(app, @SuckInLiquid_ButtonValueChanged, true);
            app.SuckInLiquid_Button.Text = 'Load liquid';
            app.PushoutLiquid_Button = uibutton(app.RewardParametersPanel, 'state');
            app.PushoutLiquid_Button.ValueChangedFcn = createCallbackFcn(app, @PushoutLiquid_ButtonValueChanged, true);
            app.PushoutLiquid_Button.Text = 'Flush liquid';
            app.DeliverRewardButton = uibutton(app.RewardParametersPanel, 'push');
            app.DeliverRewardButton.ButtonPushedFcn = createCallbackFcn(app, @DeliverRewardButtonPushed, true);
            app.DeliverRewardButton.Text = 'Deliver Reward';
            app.Switch = uiswitch(app.RewardParametersPanel, 'slider');
            app.Switch.Items = {'Left', 'Right'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Left';
            app.ExperimentalFileLabel = uilabel(app.UIFigure);
            app.ExperimentalFileLabel.Text = 'Experimental File NA';
            app.PostWeightgEditFieldLabel = uilabel(app.UIFigure);
            app.PostWeightgEditFieldLabel.Text = 'Post Weight (g)';
            app.MousepostWeight_editBox = uieditfield(app.UIFigure, 'numeric');
            app.MouseIDEditFieldLabel = uilabel(app.UIFigure);
            app.MouseIDEditFieldLabel.Text = 'Mouse ID';
            app.MouseIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.MouseIDEditField.Limits = [0 1000];
            app.MouseIDEditField.ValueChangedFcn = createCallbackFcn(app, @MouseIDEditFieldValueChanged, true);
            app.PreWeightgEditFieldLabel = uilabel(app.UIFigure);
            app.PreWeightgEditFieldLabel.Text = 'Pre Weight (g)';
            app.PreWeightgEditField = uieditfield(app.UIFigure, 'numeric');
            app.PreWeightgEditField.ValueChangedFcn = createCallbackFcn(app, @PreWeightgEditFieldValueChanged, true);
            app.StopExperimentButton = uibutton(app.UIFigure, 'push');
            app.StopExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StopExperimentButtonPushed, true);
            app.StopExperimentButton.Text = 'Stop Experiment';
            app.OverheadLightsSwitchLabel = uilabel(app.UIFigure);
            app.OverheadLightsSwitchLabel.Text = {'Overhead'; 'Lights'};
            app.OverheadLightsSwitch = uiswitch(app.UIFigure, 'toggle');
            app.OverheadLightsSwitch.ValueChangedFcn = createCallbackFcn(app, @OverheadLightsSwitchValueChanged, true);
            app.CameraLightsLabel = uilabel(app.UIFigure);
            app.CameraLightsLabel.Text = {'Camera '; 'Lights'; ''};
            app.CameraLightsSwitch = uiswitch(app.UIFigure, 'toggle');
            app.CameraLightsSwitch.ValueChangedFcn = createCallbackFcn(app, @CameraLightsSwitchValueChanged, true);
            app.CueLightSwitchLabel = uilabel(app.UIFigure);
            app.CueLightSwitchLabel.Text = {'Cue '; 'Light'; ''};
            app.CueLightSwitch = uiswitch(app.UIFigure, 'toggle');
            app.CueLightSwitch.ValueChangedFcn = createCallbackFcn(app, @CueLightSwitchValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_experiment_Manny_Jun9

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end