classdef GUI_experiment_Sept_23 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        rasterResponse_axes            matlab.ui.control.UIAxes
        handleGrasp_axes               matlab.ui.control.UIAxes
        StartExperiment_Button         matlab.ui.control.StateButton
        SessionTypeButtonGroup         matlab.ui.container.ButtonGroup
        TactileDiscriminationButton    matlab.ui.control.RadioButton
        GraspingTrainingButton         matlab.ui.control.RadioButton
        DetectionButton                matlab.ui.control.RadioButton
        RandomRewardButton             matlab.ui.control.RadioButton
        TactileDiscrminationwDistractorsButton  matlab.ui.control.RadioButton
        HandleandSpoutDetectorsSwitch  matlab.ui.control.Switch
        InitializeDetectorsLabel       matlab.ui.control.Label
        HandleandSpoutDetectorsLamp    matlab.ui.control.Lamp
        DirectorytoSaveButton          matlab.ui.control.Button
        SaveDirLabel                   matlab.ui.control.Label
        LoadExperimentalFileButton     matlab.ui.control.Button
        NotLoadedLabel                 matlab.ui.control.Label
        ExperimentalFileLamp           matlab.ui.control.Lamp
        ParametersforRandomRewardAssociationTrainingSessionsPanel  matlab.ui.container.Panel
        StimtoRewardTimeDiffmsEditFieldLabel  matlab.ui.control.Label
        StimtoRewardTimeDiffmsEditField  matlab.ui.control.NumericEditField
        LoadStimulusFileButton         matlab.ui.control.Button
        LoadStimulusFileLamp           matlab.ui.control.Lamp
        NumberoftrialsEditFieldLabel   matlab.ui.control.Label
        NumberoftrialsEditField        matlab.ui.control.NumericEditField
        StimulusfileLabel              matlab.ui.control.Label
        ITIStartmsEditField_2Label     matlab.ui.control.Label
        ITIStartmsEditField            matlab.ui.control.NumericEditField
        ITIEndmsLabel                  matlab.ui.control.Label
        ITIEndmsEditField              matlab.ui.control.NumericEditField
        GraspdurationmsLabel           matlab.ui.control.Label
        GraspTimeOutEditField          matlab.ui.control.NumericEditField
        BlockTrialsPanel               matlab.ui.container.Panel
        DeliverTrialsonBlocksCheckBox  matlab.ui.control.CheckBox
        MinBlockLabel                  matlab.ui.control.Label
        minBlock_numberEditField       matlab.ui.control.NumericEditField
        MaxBlockLabel                  matlab.ui.control.Label
        maxBlock_numberEditField       matlab.ui.control.NumericEditField
        AdvanceonCorrectCheckBox       matlab.ui.control.CheckBox
        RewardParametersPanel          matlab.ui.container.Panel
        RewardSizeuLEditFieldLabel     matlab.ui.control.Label
        RewardSizeuLEditField          matlab.ui.control.NumericEditField
        SuckInLiquid_Button            matlab.ui.control.StateButton
        PushoutLiquid_Button           matlab.ui.control.StateButton
        DeliverRewardButton            matlab.ui.control.Button
        Switch                         matlab.ui.control.Switch
        ExperimentalFileLabel          matlab.ui.control.Label
        PostWeightgEditFieldLabel      matlab.ui.control.Label
        MousepostWeight_editBox        matlab.ui.control.NumericEditField
        MouseIDEditFieldLabel          matlab.ui.control.Label
        MouseIDEditField               matlab.ui.control.NumericEditField
        PreWeightgEditFieldLabel       matlab.ui.control.Label
        PreWeightgEditField            matlab.ui.control.NumericEditField
        StopExperimentButton           matlab.ui.control.Button
        OverheadLightsSwitchLabel      matlab.ui.control.Label
        OverheadLightsSwitch           matlab.ui.control.ToggleSwitch
        CameraLightsLabel              matlab.ui.control.Label
        CameraLightsSwitch             matlab.ui.control.ToggleSwitch
        CueLightSwitchLabel            matlab.ui.control.Label
        CueLightSwitch                 matlab.ui.control.ToggleSwitch
        xminEditFieldLabel             matlab.ui.control.Label
        xmin_responseRaster            matlab.ui.control.NumericEditField
        xmaxLabel                      matlab.ui.control.Label
        xmax_responseRaster            matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        %TO access these variables below use the notation "app.variableName"
        % App parameters
        
        WW = 0;
        
        %NIDAQ and ARDUINO related parameters
        powerCapacitors = []; %variable for providing power to Left lick-spout capacitor via NI-DAQ
        powerCapacitors1 = []; %variable for providing power to Right lick-spout capacitor via NI-DAQ
        spout_and_handle_Detection = []; %variable for channels of handle and lick
        lh = []; %variable for listener
        fluidPump  = []; %GPIO for powering fluid pump
        fluidPump2 = []; %GPIO for powering fluid pump
        powerPiezo1 = []; %5v power supply GPIO pin for powering the left piezo driver
        %powerPiezo2 = []; %5v power supply GPIO pin for powering the right piezo driver
        piezoDriver = []; %analog channel for driving left piezo
        %piezoDriver2 = []; %analog channel for driving right piezo
        
        floodLight = []; %5v power supply GPIO pin for powering the rig's overhead light
        cameraLight = [];  %5v power supply GPIO pin for powering the RaspberryPi Camera's Red LEDs
        cueLight = []; %5v power supply for Cue LED
        trialLight = []; %5v power supply for indicating trial start to camera.
        
        
        %general experimental parameters
        startExperiment = nan;
        startTrialTime = nan;
        sessionStart = 0;
        experiment_started = 0; %flag for the stop experiment button
        sessionName = nan;
        
        %response and grasping parameters
        spoutLicked_left = 0; %variable indicating whether the mouse licked the left spout
        spoutLicked_right = 0; %variable indicating whether the mouse licked the left spout
        handleGrasped_left = 0; %variable indicating whether the mouse grabbed the left handle
        handleGrasped_right = 0; %variable indicating whether the mouse grabbed the right handle
        
        handleGrasped_duration = nan; %variable indicating the amount of time that the animal needs to grasp the handles for
        
        total_grasps_right = 0;
        total_grasps_left = 0;
        
        %reward parameters
        reward_given = 0;
        activePump = 'Left'; % var to hold 'left' or 'right' for controlling reward pump functions, default Left
        RewardTime = nan;
        rewardType = 'N'; % Only used in Amber's task: N=none (default), Yes_R or No_L
        sizeCodes = [] %variable for reward size signal transmission to arduino
        RewardSize = 6;
        
        %plot parameters
        lickTimes_left = nan;
        lickTimes_right = nan;
        graspTimes_left = nan;
        graspTimes_right = nan;
        grabStatus = 0; %test flag for dual grasp bars, per trial.
        
        % Stimulus Parameters
        FS = 10000; %sampling rate
        signalPiezo1 = zeros(2,10000); %1 second of signal (freq INC)
        signalPiezo2 = zeros(2,10000); %1 second of signal (freq DEC)
        signalPiezo3 = zeros(2,10000); %1 second of signal (freq CONSTANT)
        dur = nan; % duration stimulus
        dur_left = nan; %duration stimulus left
        dur_right = nan;  %duration stimulus right
        % Each of these ^ ^^ (signalPiezo1/2) contain 2 rows, one for each piezo
        stimType = 'N'; % Stimulus type N=none (default), L=left, R=right
        stimCV = 'N'; % Stimulus type Amber's task: C=constant, V=varying freq
        stimSide = 'N'; % Stimulus side (paws) N=none (default), L=left, R=right or Amber's task: Left_Paw or Right_Paw
        stimType_left = nan;
        stimType_right = nan;
        stimTime_left = nan;
        stimTime_right = nan;
        frequencies = [NaN NaN]; %for outputing each trials stim params
        freq_change = 7; %starting frequency change (adjusts with trial performance) starts at 30hz
        
        stimTime = nan;
        stimTimeOff = nan;
        visStimSide = nan;
        visStimTime = nan;
        %visualStimTime = nan;
        visualStimTimeOff = nan;
        tactileStim_timeout = nan;
        
        % Session parameters
        sessionType = 1;
        trialNum = nan;
        numTrials = nan;
        Stim_Reward_Diff = nan;
        ITI_start = nan;
        ITI_end = nan;
        graspTime = nan;
        outcome = nan;
        AdvanceOnCorrectFlag = 0;
        left_right_flag = 0;
        left_right_count = 0;
        visStimLicks = 0;
        correct_trials = 0;
        
        %curTimeGrasp = nan;
        %prevTimeGrasp = 0;
        out_times = zeros(1,2);
        out_events = strings(1,2);
        T_out = []; %output matrix of trial data/metadata
        % (TrialNum,trialStartTime,LickTimes,GraspTimes,stimTime,StimOnTime,StimOffTime,RewardSize,RewardTime,TrialEndTime)
        T = 0; % trial start time
        
        % Mouse parameters
        mouseID = 0;
        mouse_pre_weight = 0;
        mouse_post_weight = 0;
        
        % Folder parameters
        outputDirectory = nan;
        expFileLoaded = nan;
        stimLoaded = nan;
        
        inBlockDesign = nan;
        
        
    end
    
    methods (Access = public)
        
        function results = initializeDetectors(app)
            %Set up digital channels (5v out) for powering capacitative sensors
            app.powerCapacitors = daq.createSession('ni');
            addDigitalChannel(app.powerCapacitors,'dev1',['Port0/Line0', 'Port0/Line1', 'Port1/Line2', 'Port1/Line7'], 'OutputOnly');
            % Capacitors in order: (Right Handle, Left Lick Spout, Right Lick Spout, Left Handle)
            
            %set up digital channels (5v out) for controlling left fluid pump
            app.fluidPump = daq.createSession('ni');
            addDigitalChannel(app.fluidPump,'dev1','Port0/Line5:6','OutputOnly');
            
            %set up digital channels (5v out) for controlling right fluid pump
            app.fluidPump2 = daq.createSession('ni');
            addDigitalChannel(app.fluidPump2,'dev1','Port1/Line3:4','OutputOnly');
            
            %set up digital channels for encoding reward size
            app.sizeCodes = daq.createSession('ni');
            addDigitalChannel(app.sizeCodes,'dev1','Port0/Line3:4','OutputOnly');
            
            %set up digital channel for flood light
            app.floodLight = daq.createSession('ni');
            addDigitalChannel(app.floodLight, 'dev1','Port0/Line2','OutputOnly');
            
            %set up digital channel for camera light
            app.cameraLight = daq.createSession('ni');
            addDigitalChannel(app.cameraLight, 'dev1','Port1/Line1','OutputOnly');
            
            %set up digital channel for Cue light
            app.cueLight = daq.createSession('ni');
            addDigitalChannel(app.cueLight, 'dev1',['Port2/Line0', 'Port1/Line0'],'OutputOnly');
            
            %set up digital channel for trial light
            app.trialLight = daq.createSession('ni');
            addDigitalChannel(app.trialLight, 'dev1','Port2/Line1','OutputOnly');
            
            %set up digital channel for powering piezo driver 1 (Left / right handle)
            app.powerPiezo1 = daq.createSession('ni');
            addDigitalChannel(app.powerPiezo1, 'dev1',['Port0/Line7','Port2/Line2'],'OutputOnly');
            
            %set up digital channel for powering piezo driver 2 (Right handle)
            %app.powerPiezo2 = daq.createSession('ni');
            %addDigitalChannel(app.powerPiezo2, 'dev1', 'Port2/Line2','OutputOnly');
            
            %Set up analog ouput channels for driving piezos
            app.piezoDriver = daq.createSession('ni');
            addAnalogOutputChannel(app.piezoDriver, 'dev1', [0 1], 'Voltage');
            %app.piezoDriver2 = daq.createSession('ni'); %channel 2
            %addAnalogOutputChannel(app.piezoDriver2, 'dev1', 1, 'Voltage');
            app.piezoDriver.Rate = app.FS; %sampling rate
            %app.piezoDriver2.Rate = app.FS; %sampling rate
            %outputSingleScan(app.powerPiezo1,0); %hold off / low
            %outputSingleScan(app.powerPiezo2,0); %hold off / low
            
            %Setting up detection functions for spouts (Left:AI0 & Right:AI1) and handles (Left:AI2 & Right:AI6)
            app.spout_and_handle_Detection = daq.createSession('ni');
            %addAnalogInputChannel(app.spout_and_handle_Detection,'Dev1', [0 6], 'Voltage'); %spout detection
            addAnalogInputChannel(app.spout_and_handle_Detection,'Dev1', [0 1 2 6], 'Voltage'); %spout detection
            %addDigitalChannel(app.spout_and_handle_Detection,'Dev1', ['Port2/Line3','Port2/Line4','Port2/Line5','Port2/Line6'], 'InputOnly'); %spout detection
            app.spout_and_handle_Detection.Rate = 10000;
            app.spout_and_handle_Detection.IsContinuous = true;
            app.spout_and_handle_Detection.IsNotifyWhenDataAvailableExceedsAuto = true;
            app.spout_and_handle_Detection.NotifyWhenDataAvailableExceeds = 1000;
            
            outputSingleScan(app.powerCapacitors,[1,1,1,1]); %power P0.0 (handle), P0.1 (Left spout) and P1.2 (Right spout)
            
            results = 'Detectors Initialized';
            disp(results);
            app.HandleandSpoutDetectorsLamp.Color = [0 1 0];
        end
        
        
        function results = offDetectors(app)
            results = 'Detectors Off';
            
            stop(app.spout_and_handle_Detection);
            %outputSingleScan(app.powerCapacitors,[0,0,0])
            outputSingleScan(app.powerCapacitors,[0,0,0,0])
            outputSingleScan(app.powerPiezo1,[0,0]); %piezo power off
            %outputSingleScan(app.powerPiezo2,0);
            
            app.powerCapacitors = [];
            app.spout_and_handle_Detection = [];
            app.fluidPump = [];
            app.fluidPump2 = [];
            app.sizeCodes = [];
            
            disp(results);
            app.HandleandSpoutDetectorsLamp.Color = [1 0 0];
            
            % plot(app.rasterResponse_axes,randi([1 10], 1000,1),'-r');
            % hold(app.rasterResponse_axes);
            % plot(app.rasterResponse_axes,randi([1 10], 1000,1),'-b');
            % hold(app.rasterResponse_axes);
        end
        
        %function spout_detection2(src,evt)
        function spout_detection2(app, ~, event)
            
            if app.sessionStart == 0
                return
            else
            end
            
            %extracting data from NI
            chData = event.Data(:,1);
            chData2 = event.Data(:,2);
            chData3 = event.Data(:,3);
            chData4 = event.Data(:,4);
            
            %determining changes in signals
            d_chData = diff(chData);
            d_chData2 = diff(chData2);
            d_chData3 = abs(diff(chData3));
            d_chData4 = abs(diff(chData4));
            
            %getting the timing of events
            checkSpoutLeft_up = find(d_chData > 3, 1);
            checkSpoutLeft_dn = find(d_chData < -3, 1);
            checkSpoutRight_up = find(d_chData2 > 3, 1);
            checkSpoutRight_dn = find(d_chData2 < -3, 1);
            
            % find left spout licks -------------------------------------------------------
            if ~isempty(checkSpoutLeft_up)
                if ~isempty(checkSpoutLeft_dn)
                    if abs(checkSpoutLeft_dn - checkSpoutLeft_up) < 5
                        %not a true licks, just noise.
                    else
                        %disp("Left Lick!");
                        app.lickTimes_left = cat(1,app.lickTimes_left,GetSecs);
                        app.spoutLicked_left = 1;
                    end
                else
                    %disp("Left Lick!");
                    app.lickTimes_left = cat(1,app.lickTimes_left,GetSecs);
                    app.spoutLicked_left = 1;
                end
            else
                app.spoutLicked_left = 0;
            end
            
            % find right spout licks -----------------------------------------------------
            if ~isempty(checkSpoutRight_up)
                if ~isempty(checkSpoutRight_dn)
                    if abs(checkSpoutRight_dn - checkSpoutRight_up) < 5
                        %not a true licks, just noise.
                    else
                        %disp("Right Lick!");
                        app.lickTimes_right = cat(1,app.lickTimes_right,GetSecs);
                        app.spoutLicked_right = 1;
                    end
                else
                    %disp("Right Lick!");
                    app.lickTimes_right = cat(1,app.lickTimes_right,GetSecs);
                    app.spoutLicked_right = 1;
                end
            else
                app.spoutLicked_right = 0;
            end
            
            % find left bar grasps -------------------------------------------------------
            
            checkHandleLeft_up = find(d_chData3 > 3, 1);
            checkHandleLeft_dn = find(d_chData3 < -3, 1);
            
            if ~isempty(checkHandleLeft_up)
                if ~isempty(checkHandleLeft_dn)
                    if abs(checkHandleLeft_dn - checkHandleLeft_up) < 5
                        %not a true grasps, just noise.
                    else
                        if app.handleGrasped_left == 0
                            app.graspTimes_left = cat(1,app.graspTimes_left,GetSecs);
                            app.handleGrasped_left = 1;
                        else
                        end
                    end
                else
                    if app.handleGrasped_left == 0
                        app.graspTimes_left = cat(1,app.graspTimes_left,GetSecs);
                        app.handleGrasped_left = 1;
                    else
                    end
                end
            else
                if isempty(checkHandleLeft_dn)
                    if abs(mean(chData3)) < 9
                        % most of the time its at -10.6v (baseline) deflects to -7.2v when grabbed
                        % this is just noise, i.e. bar is still grasped.
                    else
                        if app.handleGrasped_left == 1
                            app.handleGrasped_left = 0;
                        else
                        end
                    end
                else
                end
            end
            
            % find right bar grasps ------------------------------------------------------
            
            checkHandleRight_up = find(d_chData4 > 3, 1);
            checkHandleRight_dn = find(d_chData4 < -3, 1);
            
            if ~isempty(checkHandleRight_up)
                if ~isempty(checkHandleRight_dn)
                    if abs(checkHandleRight_dn - checkHandleRight_up) < 5
                        %not a true grasps, just noise.
                    else
                        if app.handleGrasped_right == 0
                            app.graspTimes_right = cat(1,app.graspTimes_right,GetSecs);
                            app.handleGrasped_right = 1;
                        else
                        end
                    end
                else
                    if app.handleGrasped_right == 0
                        app.graspTimes_right = cat(1,app.graspTimes_right,GetSecs);
                        app.handleGrasped_right = 1;
                    else
                    end
                end
            else
                if isempty(checkHandleRight_dn)
                    if abs(mean(chData4)) < 9
                        % most of the time its at -10.6v (baseline) deflects to -7.2v when grabbed
                        % this is just noise, i.e. bar is still grasped.
                    else
                        if app.handleGrasped_right == 1
                            app.handleGrasped_right = 0;
                        else
                        end
                    end
                else
                end
            end
        end
    end
    
    methods (Access = private)
        
        function init_session_parameters(app)
            
            if ~isempty(app.powerCapacitors)
            else
                errordlg('Initiliaze detectors', 'modal');
                return
            end
            
            %Parameters common to all sessions
            %app.mouseID = get(app.MouseID_editBox, 'value');
            if isempty(app.mouseID)
                app.mouseID = nan;
                errordlg('Enter a Mouse ID', 'modal');
                return
            else
            end
            
            %app.mouse_pre_weight = get(app.MousepreWeight_editBox, 'value');
            %app.mouse_post_weight = get(app.MousepostWeight_editBox, 'value');
            app.outputDirectory = get(app.SaveDirLabel, 'text');
            
            if isempty(app.outputDirectory)
                app.outputDirectory = nan;
                errordlg('Enter a directory to save files', 'modal');
                return
            else
            end
            
            app.RewardSize = get(app.RewardSizeuLEditField, 'value');
            
            %----------------
            
            app.sessionStart = 0;
            app.sessionType = nan;
            app.handleGrasped_duration = nan;
            
            %determining with session to run
            if app.TactileDiscriminationButton.Value == 1
                app.sessionType = 1;
                app.Stim_Reward_Diff = get(app.StimtoRewardTimeDiffmsEditField, 'value');
                app.handleGrasped_duration = get(app.GraspTimeOutEditField, 'value');
                app.sessionName = 'Tactile_Discrimination';
                
            elseif app.GraspingTrainingButton.Value == 1
                app.sessionType = 2;
                app.graspTime = get(app.GraspTimeOutEditField, 'value');
                app.handleGrasped_duration = get(app.GraspTimeOutEditField, 'value');
                app.sessionName = 'Grasping';
                
            elseif app.DetectionButton.Value == 1
                app.sessionType = 3;
                
                if sum(abs(app.ExperimentalFileLamp.Color - [0 1 0])) == 0
                    app.expFileLoaded = get(app.ExperimentalFileLabel, 'text');
                    if isempty(app.expFileLoaded)
                        app.ExperimentalFileLamp.Color = [1 0 0];
                        app.expFileLoaded = nan;
                        errordlg('Load an Experimental file', 'modal');
                        return
                    else
                    end
                else
                    app.ExperimentalFileLamp.Color = [1 0 0];
                    app.expFileLoaded = nan;
                    errordlg('Load Experimental File', 'modal');
                    return
                end
                
                app.sessionName = 'Stim_Detection';
                
            elseif app.RandomRewardButton.Value == 1
                app.sessionType = 4;
                app.sessionName = 'Random_Reward';
                
            elseif app.TactileDiscrminationwDistractorsButton.Value == 1 % If Amber's training is selected
                app.sessionType = 5;
                app.sessionName = 'Tactile Discrmination w/ Distractors';
                
            else
                app.sessionType = nan;
            end
            
            app.numTrials = get(app.NumberoftrialsEditField, 'value');
            app.ITI_start = get(app.ITIStartmsEditField, 'value');
            app.ITI_end = get(app.ITIEndmsEditField, 'value');
            app.left_right_flag = 0;
            
            app.sessionStart = 1;
            cla(app.rasterResponse_axes);
            outputSingleScan(app.cueLight,[0,0]); %cue lights off
            
        end
        
        
        %-------------------------------------
        %-------------------------------------
        %-------------------------------------
        %-------------------------------------
        %Reward Delivery function
        function deliver_reward(app)
            % moved deliver reward from out of the spout_detection_v2_code
            if app.sessionType == 1 %Association with tactile stimulus session
                %Reward a particular side, based on Stim set
                if  strcmpi(app.stimType, "Freq_Low")
                    %left spout
                    outputSingleScan(app.fluidPump,[1,0]);
                    WaitSecs(.002);
                    outputSingleScan(app.fluidPump,[0,0]);
                    
                elseif strcmpi(app.stimType, "Freq_High")
                    %right spout
                    outputSingleScan(app.fluidPump2,[1,0]);
                    WaitSecs(.002);
                    outputSingleScan(app.fluidPump2,[0,0]);
                    
                else
                    error("Stim Type Not Updated or Recognized! in deliver_reward()");
                    return
                end
                disp('Reward Delivered.');
                app.reward_given = 1;
                
            elseif app.sessionType == 2 %Grasping training session
                
                %random reward
                if  rand() < 0.5
                    %left spout
                    outputSingleScan(app.fluidPump,[1,0]);
                    WaitSecs(.002);
                    outputSingleScan(app.fluidPump,[0,0]);
                else
                    %right spout
                    outputSingleScan(app.fluidPump2,[1,0]);
                    WaitSecs(.002);
                    outputSingleScan(app.fluidPump2,[0,0]);
                end
                
                disp('Reward Delivered.');
                app.reward_given = 1;
                
                %Sessions 3 and 4 to be done
            elseif app.sessionType == 3 %
            elseif app.sessionType == 4
            elseif app.sessionType == 5 % Amber's training session
                %Reward a particular side, based on Stim set
                if  strcmpi(app.rewardType, "Yes_R") % if cue & stimulus matched & lick correct
                    % deliver reward on right spout
                    outputSingleScan(app.fluidPump2,[1,0]);
                    WaitSecs(.002);
                    outputSingleScan(app.fluidPump2,[0,0]);
                    
                elseif strcmpi(app.rewardType, "No_L") % if cue & stimulus no matched & lick correct
                    % deliver reward on left spout
                    outputSingleScan(app.fluidPump,[1,0]);
                    WaitSecs(.002);
                    outputSingleScan(app.fluidPump,[0,0]);
                    
                else
                    error("Stim Type Not Updated or Recognized! in deliver_reward()");
                    return
                end
                disp('Reward Delivered.');
                app.reward_given = 1;
            else
            end
            
        end
        %--------------------------------------------------------------------------
        function retract_reward(app)
            % withdraw the most recent reward from the animal
            if app.sessionType == 1 % Association with tactile stimulus session
                if  strcmpi(app.stimType, "Freq_Low")
                    %left spout
                    outputSingleScan(app.fluidPump,[0,1]);
                    WaitSecs(.002);
                    outputSingleScan(app.fluidPump,[0,0]);
                elseif strcmpi(app.stimType, "Freq_High")
                    %right spout
                    outputSingleScan(app.fluidPump2,[0,1]);
                    WaitSecs(.002);
                    outputSingleScan(app.fluidPump2,[0,0]);
                else
                    error("Stim Type Not Updated or Recognized! in deliver_reward()");
                    return
                end
            elseif app.sessionType == 2 % Grasping Training Session
            elseif app.sessionType == 3 % Detection
            elseif app.sessionType == 4 % Random Reward
            elseif app.sessionType == 5 % Amber Training session
                if  strcmpi(app.stimType, "Freq_Low")
                    %left spout
                    outputSingleScan(app.fluidPump,[0,1]);
                    WaitSecs(.002);
                    outputSingleScan(app.fluidPump,[0,0]);
                elseif strcmpi(app.stimType, "Freq_High")
                    %right spout
                    outputSingleScan(app.fluidPump2,[0,1]);
                    WaitSecs(.002);
                    outputSingleScan(app.fluidPump2,[0,0]);
                else
                    error("Stim Type Not Updated or Recognized! in deliver_reward()");
                    return
                end
            end
        end
        
        %--------------------------------------------------------------------------
        function deliver_stim(app)
            
            if app.sessionType == 1 %tactile association
                %only logic for 1 stimed paw.
                
                start_f = Randi(51,1); %random frequency between [100:4:300]
                %new_f = Randi(10,1); %random frequency change between [[80:-10:10] 5 1]
                
                if app.correct_trials > 2
                    app.freq_change = app.freq_change + 1; %increase difficulty
                end
                
                if app.correct_trials < 3 && app.freq_change > 1
                    app.freq_change = app.freq_change - 1;  %decrease difficulty
                end
                
                new_f = app.freq_change;
                disp(strcat("correct_trials = ",num2str(app.correct_trials)," & new_f = ",num2str(new_f)));
                
                start_f_list = [100:4:300];
                new_f_list = [[80:-10:10] 5 1];
                
                if strcmpi(app.stimType,"Freq_low")
                    disp(strcat(app.stimSide," Stim: ",num2str(start_f_list(start_f)),"hz --> ",...
                        num2str(start_f_list(start_f)-new_f_list(new_f)),"hz"));
                    app.frequencies = [start_f_list(start_f) start_f_list(start_f)-new_f_list(new_f)];
                else
                    disp(strcat(app.stimSide," Stim: ",num2str(start_f_list(start_f)),"hz --> ",...
                        num2str(start_f_list(start_f)+new_f_list(new_f)),"hz"));
                    app.frequencies = [start_f_list(start_f) start_f_list(start_f)+new_f_list(new_f)];
                end
                
                if strcmpi(app.stimSide, "Left_Paw")
                    outputSingleScan(app.powerPiezo1,[1,0]); %power ON Left piezo
                    app.stimSide = "L";
                    if strcmpi(app.stimType, "Freq_High")
                        signal_here = [app.signalPiezo1(start_f,:,new_f)' zeros(10000,1)];
                        app.piezoDriver.queueOutputData(signal_here); %que stim data
                        app.stimType_left = "Freq_High";
                    elseif strcmpi(app.stimType, "Freq_Low")
                        signal_here = [app.signalPiezo2(start_f,:,new_f)' zeros(10000,1)];
                        app.piezoDriver.queueOutputData(signal_here); %que stim data
                        app.stimType_left = "Freq_Low";
                    else
                        error("Stim Type Not Updated or Recognized! in deliver_stim()");
                        return
                    end
                elseif strcmpi(app.stimSide,"Right_Paw")
                    outputSingleScan(app.powerPiezo1,[0,1]); %power ON Right piezo
                    app.stimSide = "R";
                    if strcmpi(app.stimType, "Freq_High")
                        signal_here = [zeros(10000,1) app.signalPiezo1(start_f,:,new_f)'];
                        app.piezoDriver.queueOutputData(signal_here); %que stim data
                        app.stimType_right = "Freq_High";
                    elseif strcmpi(app.stimType, "Freq_Low")
                        signal_here = [zeros(10000,1) app.signalPiezo2(start_f,:,new_f)'];
                        app.piezoDriver.queueOutputData(signal_here); %que stim data
                        app.stimType_right = "Freq_Low";
                    else
                        error("Stim Type Not Updated or Recognized! in deliver_stim()");
                        return
                    end
                elseif strcmpi(app.stimSide,"Both_Paws")
                    %TODO: <July 27, 2021> Integrate this. eventually... when required...
                else
                end
                
                %app.stimTime = GetSecs; %getting time of tactile stim onset
                durrr = app.dur;
                app.stimTime_left = GetSecs;
                app.dur_left = durrr * 2;
                app.stimTime_right = GetSecs;
                app.dur_right = durrr * 2;
                
                app.stimTime = GetSecs; %getting time of tactile stim onset
                app.piezoDriver.startForeground(); %start stim delivery
                outputSingleScan(app.powerPiezo1,[0,0]); %piezo power off
                outputSingleScan(app.cueLight, [0,0]); % cue lights off
                
                % ---------------------
            elseif app.sessionType == 5 % Amber Task
                % deliver stim etc...
                start_f = randi(51,1); %random frequency between
                
                % not sure if I need this increase difficulty part:
                if app.correct_trials > 2
                    app.freq_change = app.freq_change + 1; %increase difficulty
                end
                if app.correct_trials < 3 && app.freq_change > 1
                    app.freq_change = app.freq_change - 1;  %decrease difficulty
                end
                
                new_f = app.freq_change;
                disp(strcat("correct_trials = ",num2str(app.correct_trials)," & new_f = ",num2str(new_f)));
                start_f_list = [100:10:600];
                new_f_list = [[80:-10:10] 5 1];
                
                
                if strcmpi(app.stimSide,"Left_Paw") % stimulate left half of the time randomly
                    app.stimSide = "L";
                    outputSingleScan(app.powerPiezo1,[1,0]); %piezo power on
                    if strcmpi(app.stimType,"Freq_Con") % if constant freq
                        signal_here = [app.signalPiezo3(start_f, :)' zeros(length(app.signalPiezo3(start_f, :)),1)]; % zeros(10000,1)- 10000 Hz in 1 second
                    elseif strcmpi(app.stimType,"Freq_Var") % if varying freq
                        disp(strcat(app.stimSide," Stim: ",num2str(start_f_list(start_f)),"hz --> ",...
                            num2str(start_f_list(start_f)-new_f_list(new_f)),"hz"));
                        app.frequencies = [start_f_list(start_f) start_f_list(start_f)-new_f_list(new_f)];
                        if rand() < 0.5
                            % inc freq for second hlaf of 0.5 sec
                            signal_here = [app.signalPiezo1(start_f,:,new_f)' zeros(length(app.signalPiezo1(start_f,:,new_f)),1)];
                            app.piezoDriver.queueOutputData(signal_here); %que stim data
                            app.stimType_left = "Freq_High";
                        else
                            % dec freq for second hlaf of 0.5 sec
                            signal_here = [app.signalPiezo2(start_f,:,new_f)' zeros(length(app.signalPiezo2(start_f,:,new_f)),1)];
                            app.piezoDriver.queueOutputData(signal_here); %que stim data
                            app.stimType_left = "Freq_Low";
                        end
                    end
                    
                    app.piezoDriver.queueOutputData(signal_here); %que stim data
                    
                elseif strcmpi(app.stimSide,"Right_Paw")
                    app.stimSide = "R";
                    outputSingleScan(app.powerPiezo1,[0,1]); %piezo power on
                    if strcmpi(app.stimType,"Freq_Con") % if constant freq
                        signal_here = [zeros(length(app.signalPiezo3(start_f, :)),1) app.signalPiezo3(start_f, :)']; % zeros(10000,1)- 10000 Hz in 1 second
                        
                    elseif strcmpi(app.stimType,"Freq_Var") % if varying freq
                        disp(strcat(app.stimSide," Stim: ",num2str(start_f_list(start_f)),"hz --> ",...
                            num2str(start_f_list(start_f)-new_f_list(new_f)),"hz"));
                        app.frequencies = [start_f_list(start_f) start_f_list(start_f)-new_f_list(new_f)];
                        if rand() < 0.5
                            % inc freq for second hlaf of 0.5 sec
                            signal_here = [zeros(length(app.signalPiezo1(start_f,:,new_f)),1) app.signalPiezo1(start_f,:,new_f)'];
                            app.piezoDriver.queueOutputData(signal_here); %que stim data
                            app.stimType_right = "Freq_High";
                        else
                            % dec freq for second hlaf of 0.5 sec
                            signal_here = [zeros(length(app.signalPiezo2(start_f,:,new_f)),1) app.signalPiezo2(start_f,:,new_f)'];
                            app.piezoDriver.queueOutputData(signal_here); %que stim data
                            app.stimType_right = "Freq_Low";
                        end
                    end % end choosing freq
                    app.piezoDriver.queueOutputData(signal_here); %que stim data
                    
                elseif strcmpi(app.stimSide,"Both_Paws")
                    %TODO: <Sept 20, 2021> Integrate this. eventually... when required...
                else
                end % end choosing L/R paw stimulus
                
                %app.stimTime = GetSecs; %getting time of tactile stim onset
                durrr = app.dur;
                app.stimTime = GetSecs; %getting time of tactile stim onset
                app.stimTime_left = GetSecs;
                app.dur_left = durrr;
                app.stimTime_right = GetSecs;
                app.dur_right = durrr;
                app.piezoDriver.startForeground(); %start stim delivery
                outputSingleScan(app.powerPiezo1,[0,0]); %piezo power off
                outputSingleScan(app.cueLight, [0,0]); % cue lights off
            end  % end of Amber's == 5
            
        end
        
        %------------------------------
        %Turning ON the Light on the Cue Side
        function handle_LightOn(app)
            outputSingleScan(app.trialLight, 1); %indicator light for visualizing stimulus
            WaitSecs(0.005);
        end
        
        %------------------------------
        %Turning OFF the Light on the Cue Side
        function handle_LightOff(app)
            outputSingleScan(app.trialLight, 0); %indicator light for visualizing stimulus
            WaitSecs(0.005);
        end
        
        %------------------------------
        %Picking which Stimulus to present (higher or lower)
        function handle_stimType(app)
            
            if app.DeliverTrialsonBlocksCheckBox.value == 1 %checking to see whether we present stimuli in a block design
                
                if app.left_right_count == 0 %start a new block
                    
                    %pick a number of "same" trials to present in a row
                    app.left_right_count = randi([app.minBlock_numberEditField.value, app.maxBlock_numberEditField.value], 1, 1);
                    
                    %picking left or right response
                    if app.left_right_flag == 0
                        %first time picking a side
                        app.left_right_flag = randi([1 2],1,1); %random L = 1 or R = 2
                    else
                        %flipping ot the other side
                        if app.left_right_flag == 1
                            app.left_right_flag = 2;
                        elseif app.left_right_flag == 2
                            app.left_right_flag = 1;
                        else
                        end
                    end
                    
                else
                end
                
                %check to see whether we need to decrease the counter based on animals' response
                if app.AdvanceOnCorrectFlag == 1
                    if strcmpi(app.outcome, 'C')
                        app.left_right_count = app.left_right_count - 1;
                    else
                    end
                else
                    app.left_right_count = app.left_right_count - 1;
                end
                
            else
                app.left_right_count = 0;
                
                %picking a random stimulus
                if app.AdvanceOnCorrectFlag == 1
                    if app.left_right_flag == 0 %if it's the very first trial
                        app.left_right_flag = randi([1 2],1,1); %random L = 1 or R = 2
                    else
                    end
                else
                    app.left_right_flag = randi([1 2],1,1); %random L = 1 or R = 2
                end
            end
            
            % picking stimulus depending on session type
            if app.sessionType == 1
                if app.left_right_flag == 1
                    app.stimType = "Freq_Low";
                else
                    app.stimType = "Freq_High";
                end
                
            elseif app.sessionType == 5
                if app.left_right_flag == 1
                    app.stimType = "Freq_Con";
                else
                    app.stimType = "Freq_Var";
                end
            else
            end
        end
    
        %------------------------------
        %Picking which paw to present the stimulus
        function handle_stimSide(app)
            if rand() < 1 %randomly interleave some trials with stim
                if rand() < 0.5 % stimulate left half of the time randomly
                    app.stimSide = "Left_Paw";
                else
                    app.stimSide = "Right_Paw";
                end
            else
            end
        end
        

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         
        end

        % Value changed function: HandleandSpoutDetectorsSwitch
        function HandleandSpoutDetectorsSwitchValueChanged(app, event)
            value = app.HandleandSpoutDetectorsSwitch.Value;
            if strcmpi(value, 'on')
                initializeDetectors(app);
            else
                offDetectors(app);
            end
        end

        % Value changed function: StartExperiment_Button
        function StartExperiment_ButtonValueChanged(app, event)
                
                init_session_parameters(app);
                
                %number of max trials in a raster plot fpor display (it updates the raster if it's larger)
                maxTrialRaster = app.numTrials;
                
                %----------
                %SEND PULSE TO R-PI to synch both clocks
                %---------
                
                disp(app.trialNum);
                
                app.experiment_started = 1;
                
                
                if app.sessionStart == 1
                    
                    %-----------------
                    %Creating output file
                    %Output the saved data (out_times and out_events)
                    disp('Saving Experiment data...')
                    working_dir = pwd; %current directory pre-save
                    app.T_out = [];
                    
                    %find correct folder to save data into
                    try
                        cd(app.outputDirectory); %should be specified earlier in GUI.
                    catch
                        disp('No mouse directory specified - saving file to Desktop...');
                        app.outputDirectory = 'C:\Users\ramirezlab\Desktop';
                        cd(app.outputDirectory);
                    end
                    
                    %-----------------
                    %see if mouse directory exists
                    app.mouseID = num2str(app.MouseIDEditField.Value);
                    if isfolder([working_dir '\' app.mouseID]) ~= 1
                        mkdir([working_dir '\' app.mouseID])
                    else
                    end
                    
                    currDate = datestr(date,2);
                    currDate(strfind(currDate,'/')) = '_';
                    
                    if isfolder([working_dir '\' app.mouseID '\' currDate]) ~= 1
                        mkdir([working_dir '\' app.mouseID '\' currDate]);
                    else
                    end
                    
                    curDirFile = dir([working_dir '\' app.mouseID '\' currDate]);
                    curDirFile = {curDirFile.name};
                    idx_file = 0;
                    for ff = 1 : length(curDirFile)
                        tempFile = curDirFile{ff};
                        if contains(tempFile,[app.mouseID '_' app.sessionName])
                            idx_file = idx_file + 1;
                        else
                        end
                    end
                    
                    if idx_file < 10
                        idx_file = ['00' num2str(idx_file)];
                    elseif idx_file >= 10 && idx_file < 100
                        idx_file = ['0' num2str(idx_file)];
                    else
                        idx_file = num2str(idx_file);
                    end
                    
                    outData = app.T_out;
                    save([working_dir '\' app.mouseID '\' currDate '\' app.mouseID '_' app.sessionName '_' idx_file], 'outData');
                    %
                    %                 % TODO create unique file name for mouse (m235_2021_01_29_001) Mouse#_Year_Month_Day_Exp#
                    %                 currDate = strrep(datestr(datetime), ':', '_');
                    %                 exp_name = strcat('m', num2str(app.mouseID),'_',currDate,'.mat');
                    %                 %exp_name = 'Test_File_Outport.mat';
                    %                 Table_out = app.T_out;
                    %                 save(exp_name,'Table_out');
                    %                 pause(0.1);
                    %                 disp('Experiment data saved!')
                    %----------------------
                    
                    totalTrials = app.numTrials;
                    
                    if app.sessionType == 1 %Association with tactile stimulus session
                        app.grabStatus = 0; %reset flag
                    else
                        errordlg('Weird State ... Re-check all parameters', 'modal');
                        return
                    end
                    
                    if app.sessionType == 5 % Amber's tactile session
                        app.grabStatus = 0; %reset flag
                    else
                        errordlg('Weird State ... Re-check all parameters', 'modal');
                        return
                    end
                    
                    app.StartExperiment_Button.BackgroundColor = [0 1 0];
                    
                    % --------------------------
                    % Power and inititate daq devices
                    % disp('Powering capacitors');
                    % outputSingleScan(app.powerCapacitors,[1,1,1,1]); %power P0.0 (handle), P0.1 (Left spout) and P1.2 (Right spout)
                    % outputSingleScan(app.powerCapacitors1, 1); %power P1.2 (Right spout)
                    % pause(.1);
                    disp('Starting listener');
                    app.lh = addlistener(app.spout_and_handle_Detection,'DataAvailable', ...
                        @(src,event) spout_detection2(app, src, event));
                    pause(.1);
                    disp('Starting collection of data in the background');
                    startBackground(app.spout_and_handle_Detection);
                    % --------------------------
                    
                    % ----------
                    % getting the start time of the experiment after all the listener and NIDAQ sources have been set up
                    app.startExperiment = GetSecs;
                    app.trialNum = 1;
                    
                    %main trial loop
                    pause(2);
                    
                    for nn = 1 : totalTrials
                        
                        disp("------------------------------");
                        disp(strcat("left_right_flag = ",num2str(app.left_right_flag)));
                        %-----------------
                        %Resetting trial variables
                        %-----------------
                        app.lickTimes_left = [];
                        app.lickTimes_right = [];
                        app.graspTimes_left = [];
                        app.graspTimes_right = [];
                        
                        
                        app.visStimSide = nan;
                        app.visStimTime = nan;
                        app.visStimLicks = 0;
                        
                        app.stimTime = nan;
                        app.stimSide = nan;
                        app.stimType_left = nan;
                        app.stimType_right = nan;
                        app.stimTime_left = nan;
                        app.stimTime_right = nan;
                        app.dur_left = nan;
                        app.dur_right = nan;
                        app.stimTimeOff = nan;
                        
                        app.RewardSize = nan;
                        app.RewardTime = nan;
                        
                        app.startTrialTime = nan;
                        
                        %-----------------
                        %-----------------
                        
                        %getting the start of the trial
                        app.startTrialTime = GetSecs;
                        
                        %send Raspberry video trigger to log time point of trial start
                        %---------
                        %MATLAB sends trigger to rasp pi (TTL pulse)
                        %rasp pi checks for line high (or low)
                        %if line is high, then record time and send to MATLAB for logging into MAT-FILE behavior (T-OUT)
                        %----------
                        
                        if app.sessionStart == 0
                            break;
                        else
                        end
                        app.trialNum = nn;
                        disp(['Trial Number: ' num2str(nn)]);
                        
                        iti_start = get(app.ITIStartmsEditField, 'Value');
                        iti_end = get(app.ITIEndmsEditField, 'Value');
                        
                        if app.sessionType == 1  || app.sessionType == 5  % Tactile discrimination task (1 = without distracters, 5 = with distracters in opposite paw)
                            
                            %-----------------------
                            %-----------------------
                            
                            %going ahead until the animal has grabbed both handles
                            goAhead = 0;
                            grabTimer = GetSecs;
                            startCounter = 0;
                            disp('waiting for animal start')
                            %disp('Starting Grab Loop');
                            while  goAhead == 0
                                pause(0);
                                % listen for licks ------------------------------------------
                                if app.spoutLicked_left == 1 || app.spoutLicked_right == 1
                                    %resetting timer
                                    grabTimer = GetSecs;
                                    %disp("bad mouse - stop licking");
                                else
                                end
                                
                                %listen for grasps-------------------------------------------
                                if app.handleGrasped_left == 1 && app.handleGrasped_right == 1
                                    if startCounter == 0
                                        grabTimer = GetSecs;
                                        startCounter = 1;
                                    else
                                    end
                                else
                                    %resetting timer
                                    startCounter = 0;
                                    grabTimer = GetSecs;
                                end
                                
                                %check timer------------------------------------------------
                                if ((GetSecs - grabTimer) * 1000) > 1000
                                    %grabbed for 1 sec
                                    %goAhead = 1; %#ok
                                    break
                                else
                                end
                                
                                %check for stop---------------------------------------------
                                if app.sessionStart == 0
                                    break
                                else
                                end
                            end
                            
                            %disp('exit from grab loop');
                            
                            if app.sessionStart == 0
                                return
                            else
                            end
                            
                            
                            %-----------------------
                            %figuring out which paw to deliver stimulus
                            handle_stimSide(app);
                            
                            %figuring out which type of stimulus to present (higher or lower frequency)
                            handle_stimType(app);
                            
                            %----------------------------------------------------------------------
                            cue_flag = 0;
                            goAhead2 = 0;
                            startCounter = 0;
                            grabTimer = GetSecs;
                            cue_light_dur = 500+floor(rand()*1000);
                            
                            while goAhead2 == 0
                                pause(0);
                                %present visual cue------------------------------------------------
                                if cue_flag == 0
                                    app.visStimTime = GetSecs; %getting time of visual stim onset
                                    if strcmpi(app.stimSide, "Left_Paw")
                                        app.visStimSide = "L"; %getting the side of visual stim (cue)
                                        outputSingleScan(app.cueLight,[1,0]); %power ON Left cue light
                                    elseif strcmpi(app.stimSide,"Right_Paw")
                                        app.visStimSide = "R";
                                        outputSingleScan(app.cueLight,[0,1]); %power ON Right cue light
                                    else
                                    end
                                    cue_flag = 1;
                                else
                                end
                                %check for licks---------------------------------------------------
                                if app.spoutLicked_left == 1 || app.spoutLicked_right == 1
                                    %disp("bad mouse - stop licking");
                                    app.visStimLicks = app.visStimLicks + 1;
                                    outputSingleScan(app.cueLight,[0,0]); %power OFF cue lights
                                    pause(0.1);
                                    %flash cue lights
                                    outputSingleScan(app.cueLight,[1,1]); %power ON cue lights
                                    pause(0.1);
                                    outputSingleScan(app.cueLight,[0,0]); %power OFF cue lights
                                    grabTimer = GetSecs; %reset timer
                                    cue_flag = 0; %reset cue flag
                                    
                                else
                                end
                                %listen for grasps-------------------------------------------
                                if app.handleGrasped_left == 1 && app.handleGrasped_right == 1
                                    if startCounter == 0
                                        grabTimer = GetSecs;
                                        startCounter = 1;
                                    else
                                    end
                                else
                                    %resetting timer
                                    startCounter = 0;
                                    grabTimer = GetSecs;
                                end
                                %check timer-------------------------------------------------------
                                if ((GetSecs - grabTimer) * 1000) > cue_light_dur
                                    %goAhead = 1; %#ok
                                    app.visStimTime = [app.visStimTime GetSecs];
                                    break
                                else
                                end
                                %check for stop----------------------------------------------------
                                if app.sessionStart == 0
                                    break
                                else
                                end
                                
                            end
                            
                            %Turn on light on the cued side
                            if strcmpi(app.stimSide, "Left_Paw")
                                outputSingleScan(app.cueLight,[1,0]); %power ON Left cue light
                            elseif strcmpi(app.stimSide,"Right_Paw")
                                outputSingleScan(app.cueLight,[0,1]); %power ON Right cue light
                            else
                            end
                            
                            %pause(1);
                            
                            %turn off cue light
                            %outputSingleScan(app.cueLight, [0,0]); %power off cue lights.
                            
                            %figuring out  reward parameters
                            app.RewardSize = get(app.RewardSizeuLEditField,'Value');
                            timeRewardToStim = app.Stim_Reward_Diff;
                            
                            if timeRewardToStim <= 0
                                startTimerReward = GetSecs;
                                %provide reward
                                app.RewardTime = GetSecs;
                                deliver_reward(app);
                                %wait
                                while (GetSecs - startTimerReward) <= abs(timeRewardToStim)
                                    pause(0);
                                    if app.sessionStart == 0
                                        break
                                    else
                                    end
                                end
                                
                                %Deliver the tactile stimulus
                                deliver_stim(app);
                                
                            else
                                %Deliver the tactile stimulus
                                deliver_stim(app);
                                
                                %pause for the first frequency to be delivered
                                pause_timer = GetSecs;
                                while GetSecs - pause_timer > 0.5
                                end
                                
                                %provide reward
                                %app.RewardTime = GetSecs;
                                %deliver_reward(app);
                                
                                %============================================================================================================
                                %disp("Entering Lick-go-ahead-section")
                                reward_timeout = GetSecs;
                                goAhead = 0;
                                while  goAhead == 0
                                    pause(0);
                                    
                                    %------------
                                    %stimulus choices depending on session type
                                    stimChoice1 = []; stimChoice2 = [];
                                    
                                    if app.sessionType == 1
                                        stimChoice1  = 'Freq_Low';
                                        stimChoice2  = 'Freq_High';
                                    elseif app.sessionType == 5
                                        stimChoice1  = 'Freq_Con';
                                        stimChoice2  = 'Freq_Var';
                                    end
                                    
                                    %----------
                                    
                                    if app.spoutLicked_left == 1
                                        if  strcmpi(app.stimType, stimChoice1)
                                            % left spout rewarded
                                            % good mouse
                                            %provide reward
                                            app.RewardTime = GetSecs;
                                            deliver_reward(app);
                                            disp("Licked left, correct");
                                            app.outcome = "C";
                                            %app.left_right_flag = app.left_right_flag + 1;
                                            app.correct_trials = app.correct_trials + 1;
                                            goAhead = 1; %break;
                                        elseif strcmpi(app.stimType, stimChoice2)
                                            % right spout rewarded
                                            % bad mouse
                                            % retract droplet
                                            % retract_reward(app)
                                            disp("Licked left, incorrect");
                                            app.correct_trials = 0; %reset counter
                                            app.outcome = "I";
                                            goAhead = 1;
                                        else
                                            error("Stim Type Not Updated or Recognized! in Lick-Go-Ahead-section)");
                                            return
                                        end
                                        
                                    elseif app.spoutLicked_right == 1
                                        if  strcmpi(app.stimType, stimChoice1)
                                            % left spout rewarded
                                            % bad mouse
                                            % retract droplet
                                            % retract_reward(app)
                                            disp("licked right, incorrect")
                                            app.outcome = "I";
                                            app.correct_trials = 0; %reset counter
                                            goAhead = 1;
                                        elseif strcmpi(app.stimType, stimChoice2)
                                            % right spout rewarded
                                            % good mouse
                                            %provide reward
                                            app.RewardTime = GetSecs;
                                            deliver_reward(app);
                                            disp("Licked right, correct");
                                            app.outcome = "C";
                                            %app.left_right_flag = app.left_right_flag + 1;
                                            app.correct_trials = app.correct_trials + 1;
                                            goAhead = 1;
                                        else
                                            error("Stim Type Not Updated or Recognized! in Lick-Go-Ahead-section)");
                                            return
                                        end
                                        
                                    else
                                    end
                                    
                                    if (GetSecs - reward_timeout > 1.5)
                                        disp("Lick Timeout");
                                        %retract_reward(app);
                                        app.correct_trials = 0;
                                        goAhead = 1;
                                    else
                                    end
                                    if app.sessionStart == 0
                                        break
                                    else
                                    end
                                end
                                %============================================================================================================
                                
                            end
                            
                            %Turn off light on the cued side
                            handle_LightOff(app)
                            
                        elseif app.sessionType == 2 %Grasping training
                            
                            %-----------------------
                            %-----------------------
                            
                            %going ahead until the animal has grabbed both handles
                            goAhead = 0;
                            grabTimer = GetSecs;
                            
                            while goAhead == 0
                                
                                if app.handleGrasped_left == 1 && app.handleGrasped_right == 1
                                    if (GetSecs - grabTimer) * 1000 > 1000
                                        goAhead = 1;
                                    else
                                    end
                                else
                                    %resetting timer
                                    grabTimer = GetSecs;
                                end
                                
                                if app.sessionStart == 0
                                    break
                                else
                                end
                                
                                pause(0);
                                
                                %turning off stimulus (fail safe)
                                if (GetSecs - app.stimTime) > app.dur
                                    outputSingleScan(app.powerPiezo1,[0,0]); %piezo power off
                                else
                                end
                                
                            end
                            %-----------------------
                            %-----------------------
                            
                            %figuring out  reward parameters
                            app.RewardSize = get(app.RewardSizeuLEditField,'Value');
                            timeRewardToStim = app.Stim_Reward_Diff; %#ok
                            %provide reward
                            app.RewardTime = GetSecs;
                            deliver_reward(app);
                            
                        elseif app.sessionType == 3 %detection session
                            
                            %figuring out which paw to deliver stimulus
                            handle_stimSide(app);
                            
                            %figuring out which type of stimulus to present (higher or lower frequency)
                            handle_stimType(app)
                            
                            %figuring out  reward parameters
                            app.RewardSize = get(app.RewardSizeuLEditField,'Value');
                            timeRewardToStim = app.Stim_Reward_Diff;
                            
                            if timeRewardToStim <= 0
                                startTimerReward = GetSecs;
                                %provide reward
                                app.RewardTime = GetSecs;
                                deliver_reward(app);
                                %wait
                                while (GetSecs - startTimerReward) <= abs(timeRewardToStim)
                                    pause(0);
                                    
                                    %turning off stimulus (fail safe)
                                    if (GetSecs - app.stimTime) > app.dur
                                        outputSingleScan(app.powerPiezo1,[0,0]); %piezo power off
                                    else
                                    end
                                    
                                    if app.sessionStart == 0
                                        break
                                    else
                                    end
                                    
                                end
                                
                                %Deliver the tactile stimulus
                                deliver_stim(app);
                                
                            else
                                
                                %Deliver the tactile stimulus
                                deliver_stim(app);
                                
                                %provide reward
                                app.RewardTime = GetSecs;
                                deliver_reward(app);
                            end
                            
                        elseif app.sessionType == 4 %reward session
                            
                            %figuring out  reward parameters
                            app.RewardSize = get(app.RewardSizeuLEditField,'Value');
                            timeRewardToStim = app.Stim_Reward_Diff; %#ok
                            
                            %provide reward
                            deliver_reward(app);
                            
                            %--------------------------------------
                        else                            
                        end % end "if app.sessiontype == x"
                        
                        if app.sessionStart == 0
                            break
                        else
                        end
                        
                        
                        %Presenting the inter trial interval
                        pickITI = randi([iti_start iti_end],1,1)/1000;
                        %plotMe = 1;
                        
                        while (GetSecs - app.startTrialTime) <= pickITI
                            
                            pause(0);
                            %turning off stimulus (fail safe)
                            if (GetSecs - app.stimTime) > app.dur
                                outputSingleScan(app.powerPiezo1,[0,0]); %piezo power off
                            else
                            end
                            
                            if app.sessionStart == 0
                                break
                            else
                            end
                            
                        end
                        
                        %--------------------------------
                        %plotting and saving data
                        %if left spout licking was detected
                        if ~isempty(app.lickTimes_left)
                            %lickTimesRaster = nan;
                            lickTimes2 = round((app.lickTimes_left - app.stimTime) * 1000);
                            plot(app.rasterResponse_axes, lickTimes2, zeros(length(lickTimes2),1) + nn, '.b');
                            hold(app.rasterResponse_axes,'on');
                            
                            if nn > maxTrialRaster
                                axis(app.rasterResponse_axes, [get(app.xmin_responseRaster, 'value') ...
                                    get(app.xmax_responseRaster, 'value') 1 nn + 1]);
                            else
                                try
                                    axis(app.rasterResponse_axes, [get(app.xmin_responseRaster, 'value') ...
                                        get(app.xmax_responseRaster, 'value') 1 maxTrialRaster]);
                                catch
                                    disp("weird plotting error...");
                                end
                            end
                            
                        else
                            disp('no left licks this trial')
                        end
                        
                        %if right spout licking was detected
                        if ~isempty(app.lickTimes_right)
                            %lickTimesRaster = nan;
                            lickTimes2 = round((app.lickTimes_right - app.stimTime) * 1000);
                            plot(app.rasterResponse_axes, lickTimes2, zeros(length(lickTimes2),1) + nn, '.r');
                            hold(app.rasterResponse_axes,'on');
                            
                            if nn > maxTrialRaster
                                axis(app.rasterResponse_axes, [get(app.xmin_responseRaster, 'value') ...
                                    get(app.xmax_responseRaster, 'value') 1 nn + 1]);
                            else
                                try
                                    axis(app.rasterResponse_axes, [get(app.xmin_responseRaster, 'value') ...
                                        get(app.xmax_responseRaster, 'value') 1 maxTrialRaster]);
                                catch
                                    disp("weird plot error");
                                end
                            end
                            
                        else
                            disp('no right licks this trial')
                        end
                        
                        app.T_out{nn,1} = nn;
                        app.T_out{nn,2} = app.startTrialTime;
                        
                        app.T_out{nn,3} = app.lickTimes_left;
                        app.T_out{nn,4} = app.lickTimes_right;
                        
                        app.T_out{nn,5} = app.graspTimes_left;
                        app.T_out{nn,6} = app.graspTimes_right;
                        
                        app.T_out{nn,7} = app.visStimSide;
                        app.T_out{nn,8} = app.visStimTime;
                        
                        app.T_out{nn,9} = app.stimSide;
                        
                        app.T_out{nn,10} = app.stimType_left;
                        app.T_out{nn,11} = app.stimType_right;
                        
                        app.T_out{nn,12} = app.stimTime_left ;
                        app.T_out{nn,13} = app.stimTime_right ;
                        
                        app.T_out{nn,14} = app.dur_left ;
                        app.T_out{nn,15} = app.dur_right ;
                        
                        app.T_out{nn,16} = app.RewardSize;
                        app.T_out{nn,17} = app.RewardTime;
                        
                        app.T_out{nn,18} = app.outcome;
                        
                        app.T_out{nn,19} = app.visStimLicks;
                        
                        app.T_out{nn,20} = app.frequencies;
                        
                        % save the cell variable holding all the information on a trial by trial basis
                        outData = app.T_out;
                        save([working_dir '\' app.mouseID '\' currDate '\' app.mouseID '_' app.sessionName '_' idx_file],'outData');
                        
                        app.lickTimes_left = [];
                        app.lickTimes_right = [];
                        
                        pause(.05);
                        %--------------------------------
                        %--------------------------------
                        
                    end %end trial loop nn
                    
                    
                    disp('end block of trials')
                    app.out_times = [app.out_times GetSecs - app.T];
                    app.out_events = [app.out_events "End_Of_Trials"];
                    
                else
                    try
                        stop(app.spout_and_handle_Detection);
                    catch
                    end
                    app.StartExperiment_Button.Value = 0;
                    app.StartExperiment_Button.BackgroundColor = [.96 .96 .96];
                end
                
                try
                    stop(app.spout_and_handle_Detection);
                catch
                end
                
                %TODO save a metadata file along with the experiment file (m235_2021_001_29_001_meta)
                % mouse weight pre.
                % mouse weight post .
                
                app.StartExperiment_Button.Value = 0;
                app.StartExperiment_Button.BackgroundColor = [.96 .96 .96];
                
                app.experiment_started = 0; %turn off flag for stop button.
                
        end

        % Button pushed function: DeliverRewardButton
        function DeliverRewardButtonPushed(app, event)
                % deliver the reward to the animal
                % through Digital channel P0.6 (pin16)
                disp('Reward Delivered.');
                if  strcmp(app.activePump, 'Left')
                    outputSingleScan(app.fluidPump,[1,0]);
                    pause(.1);
                    outputSingleScan(app.fluidPump,[0,0]);
                elseif  strcmp(app.activePump, 'Right')
                    outputSingleScan(app.fluidPump2,[1,0]);
                    pause(.1);
                    outputSingleScan(app.fluidPump2,[0,0]);
                else
                    disp('ERROR: No Active Pump');
                end
                
                
        end

        % Value changed function: PushoutLiquid_Button
        function PushoutLiquid_ButtonValueChanged(app, event)
                % function for continuous flush of the fluid pump
                % send a 5v step until the button is un-selected
                value = app.PushoutLiquid_Button.Value;
                if value == 1
                    if strcmp(app.activePump, 'Left')
                        disp('Left Pump Extruding')
                        outputSingleScan(app.fluidPump, [1,0]);
                    elseif strcmp(app.activePump, 'Right')
                        disp('Right Pump Extruding')
                        outputSingleScan(app.fluidPump2, [1,0]);
                    else
                        error('No Active Pump!');
                    end
                else
                    disp('Pump Stopped')
                    outputSingleScan(app.fluidPump, [0,0]);
                    outputSingleScan(app.fluidPump2, [0,0]);
                end
        end

        % Value changed function: SuckInLiquid_Button
        function SuckInLiquid_ButtonValueChanged(app, event)
                value = app.SuckInLiquid_Button.Value;
                % function for continuous retracting of the fluid pump
                % send a 5v step until the button is un-selected
                if value == 1
                    if strcmp(app.activePump, 'Left')
                        disp('Left Pump Retracting')
                        outputSingleScan(app.fluidPump, [0,1]);
                    elseif strcmp(app.activePump, 'Right')
                        disp('Right Pump Retracting')
                        outputSingleScan(app.fluidPump2, [0,1]);
                    else
                        error('No Active Pump!');
                    end
                else
                    disp('Pump Stopped')
                    outputSingleScan(app.fluidPump, [0,0]);
                    outputSingleScan(app.fluidPump2, [0,0]);
                end
        end

        % Callback function
        function MouseID_editBoxValueChanged(app, event)
                %NOT USED
        end

        % Value changed function: MouseIDEditField
        function MouseIDEditFieldValueChanged(app, event)
                value = app.MouseIDEditField.Value;
                % input value (numeric) for mouse ID number
                disp(strcat("Welcome! Mouse ", num2str(value)));
                app.mouseID = value;
                
                % Change current directory to this Mouse's Folder
                
                % Save experiment data to this location
                
                % Figure out function logic for if the user inputs mouse number or mouse weight first.
        end

        % Value changed function: PreWeightgEditField
        function PreWeightgEditFieldValueChanged(app, event)
                value = app.PreWeightgEditField.Value;
                app.mouse_pre_weight = value;
                mouseNum = app.MouseIDEditField.Value;
                disp(strcat("Mouse ",num2str(mouseNum)," weighs ",num2str(value),"g."));
        end

        % Value changed function: RewardSizeuLEditField
        function RewardSizeuLEditFieldValueChanged(app, event)
                value = app.RewardSizeuLEditField.Value;
                curr_size = app.RewardSize;
                disp(strcat("Old reward size: ",num2str(curr_size),"uL"));
                % A new value has been assigned to the Reward Size parameter.
                disp(strcat("New reward size: ",num2str(value),"uL"));
                % Send update code to arduino
                while abs(curr_size - value)>0
                    if value > curr_size
                        disp("up one tick");
                        outputSingleScan(app.sizeCodes, [0,1]);
                        pause(.01);
                        outputSingleScan(app.sizeCodes, [0,0]);
                        pause(.01);
                        curr_size = curr_size + 1;
                    else
                        disp("down one tick");
                        outputSingleScan(app.sizeCodes, [1,0]);
                        pause(.01);
                        outputSingleScan(app.sizeCodes, [0,0]);
                        pause(.01);
                        curr_size = curr_size - 1;
                    end
                end
                app.RewardSize = value; %update global var
                %             outputSingleScan(app.fluidPump, [1,1]);
                %             if value <= 5
                %                 %low reward size
                %                 disp('Small reward.');
                %                 app.RewardSize = 'small 0-5uL';
                %                 outputSingleScan(app.sizeCodes, [0,0]);
                %             elseif value > 5 && value <= 10
                %                 %med reward size
                %                 disp('Medium reward.');
                %                 app.RewardSize = 'medium 5-10uL';
                %                 outputSingleScan(app.sizeCodes, [1,0]);
                %                 pause(.1);
                %                 outputSingleScan(app.sizeCodes, [0,0]);
                %             elseif value > 10 && value <= 15
                %                 %large reward
                %                 disp("Large reward.");
                %                 app.RewardSize = 'large 10-15uL';
                %                 outputSingleScan(app.sizeCodes, [0,1]);
                %                 pause(.1);
                %                 outputSingleScan(app.sizeCodes, [0,0]);
                %             elseif value > 15
                %                 %extra-large reward
                %                 disp("Massive reward.");
                %                 app.RewardSize = 'massive 15-20uL';
                %                 outputSingleScan(app.sizeCodes, [1,1]);
                %                 pause(.1);
                %                 outputSingleScan(app.sizeCodes, [0,0]);
                %             else
                %                 error('Reward Size Not Possible');
                %             end
                %             outputSingleScan(app.fluidPump, [0,0]);
                
        end

        % Button pushed function: DirectorytoSaveButton
        function DirectorytoSaveButtonPushed(app, event)
                % Get user input for which directory to save the data into
                dir_name = uigetdir('C:\Users\ramirezlab\Desktop\Mouse_Data\');
                disp(dir_name);
                app.outputDirectory = dir_name;
                % update the current directory to save the data into.
        end

        % Button pushed function: StopExperimentButton
        function StopExperimentButtonPushed(app, event)
                flag = app.experiment_started;
                if flag == 1
                    % Basically UNDO anyting that the start experiemnt button did.
                    app.sessionStart = 0; %halts current trial
                    % SAVE DATA
                    disp('Saving Experiment data...');
                    %Outport the saved data (out_times and out_events)
                    working_dir = pwd; %#ok
                    %find correct folder to save data into
                    try
                        cd(app.outputDirectory); %should be specified earlier in GUI.
                    catch
                        disp('No mouse directory specified - saving file to Desktop...');
                        app.outputDirectory = 'C:\Users\ramirezlab\Desktop';
                        cd(app.outputDirectory);
                    end
                    %disp(app.out_events(1));
                    currDate = strrep(datestr(datetime), ':', '_');
                    exp_name = strcat('m', num2str(app.mouseID),'_',currDate,'.mat');
                    %exp_name = 'Test_File_Outport.mat';
                    Table_out = app.T_out;
                    save(exp_name,'Table_out');
                    disp('Experiment data saved!')
                else
                    disp("No experiment to stop...");
                end
                
                
        end

        % Value changed function: OverheadLightsSwitch
        function OverheadLightsSwitchValueChanged(app, event)
                % Turn overhead lights ON and OFF
                % P0.2 for power
                value = app.OverheadLightsSwitch.Value;
                if strcmp(value,'On')
                    outputSingleScan(app.floodLight, 1);
                elseif strcmp(value, 'Off')
                    outputSingleScan(app.floodLight, 0);
                end
        end

        % Value changed function: NumberoftrialsEditField
        function NumberoftrialsEditFieldValueChanged(app, event)
                value = app.NumberoftrialsEditField.Value;
                disp(strcat("New number of trials is: ",num2str(value)));
        end

        % Value changed function: CameraLightsSwitch
        function CameraLightsSwitchValueChanged(app, event)
                % Turn RPi camera lights ON and OFF
                % P1.1 for power
                value = app.CameraLightsSwitch.Value;
                if strcmp(value,'On')
                    outputSingleScan(app.cameraLight, 1);
                elseif strcmp(value, 'Off')
                    outputSingleScan(app.cameraLight, 0);
                end
        end

        % Value changed function: CueLightSwitch
        function CueLightSwitchValueChanged(app, event)
                % Turn front cue light ON and OFF
                % P1.0 / P2.0 for power
                value = app.CueLightSwitch.Value;
                if strcmp(value,'On')
                    outputSingleScan(app.cueLight, [1,1]);
                elseif strcmp(value, 'Off')
                    outputSingleScan(app.cueLight, [0,0]);
                end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
                value = app.Switch.Value;
                disp(strcat("active pump: ", string(value)));
                app.activePump = value;
        end

        % Button pushed function: LoadStimulusFileButton
        function LoadStimulusFileButtonPushed(app, event)
                % Create the piezo stimulus file
                % TODO: move these variables up into global app.variables
                % app.signalPiezo = (51 x 10000 x 10) zeros
                
                if app.sessionType == 1
                    freq1 = [100:4:300];
                    freq2 = [80,70,60,50,40,30,20,10,5,1];
                    app.dur = 0.5;
                    amp = -3;
                    t = 0:1/app.FS:app.dur - 1/app.FS;
                    
                    for i = 1 : length(freq1)
                        for ii = 1 : length(freq2)
                            %inc freq
                            app.signalPiezo1(i,1:length(t),ii) = amp*sin(2*pi*t*freq1(i));
                            app.signalPiezo1(i,length(t)+1:end,ii) = amp*sin(2*pi*t*(freq1(i)+freq2(ii)));
                            %dec freq
                            app.signalPiezo2(i,1:length(t),ii) = amp*sin(2*pi*t*freq1(i));
                            app.signalPiezo2(i,length(t)+1:end,ii) = amp*sin(2*pi*t*(freq1(i)-freq2(ii)));
                        end
                    end
                    
                    app.dur = app.dur * 2;
                    
                    disp("Stimulus file loaded successfully...")
                    app.LoadStimulusFileLamp.Color = [0 1 0];
                    
                elseif app.sessionType == 5
                    % frequency with freq change half way through
                    freq1 = [100:10:600];
                    freq2 = [80,70,60,50,40,30,20,10,5,1];
                    app.dur = 0.5;
                    amp = -3;
                    t = 0:1/app.FS:app.dur - 1/app.FS;
                    
                    for i = 1 : length(freq1)
                        for ii = 1 : length(freq2)
                            %inc freq
                            app.signalPiezo1(i,1:length(t),ii) = amp*sin(2*pi*t*freq1(i));
                            app.signalPiezo1(i,length(t)+1:end,ii) = amp*sin(2*pi*t*(freq1(i)+freq2(ii)));
                            %dec freq
                            app.signalPiezo2(i,1:length(t),ii) = amp*sin(2*pi*t*freq1(i));
                            app.signalPiezo2(i,length(t)+1:end,ii) = amp*sin(2*pi*t*(freq1(i)-freq2(ii)));
                        end
                    end
                    
                    app.dur = app.dur * 2;
                    
                    % Constant frequency
                    freq3 = [100:10:600]; % from 100 starting freq with 1 step of 10 to 600 freq, with 51 items
                    app.dur = 1;
                    amp = -3;
                    t = 0:1/app.FS:app.dur - 1/app.FS;
                    for i = 1 : length(freq3)
                        app.signalPiezo3(i,1:length(t)) = amp*sin(2*pi*t*freq3(i)); % create 51 (row-freq)x10000 (column-time)array
                    end
                    
                    disp("Stimulus file loaded successfully...")
                    app.LoadStimulusFileLamp.Color = [0 1 0];
                    
        end

        % Value changed function: xmin_responseRaster
        function xmin_responseRasterValueChanged(app, event)
                    value = app.xmin_responseRaster.Value;
                    if value > get(app.xmax_responseRaster, 'value')
                        app.xmin_responseRaster.Value = get(app.xmax_responseRaster, 'value');
                    else
                    end
                    
        end

        % Value changed function: xmax_responseRaster
        function xmax_responseRasterValueChanged(app, event)
                    
                    value = app.xmax_responseRaster.Value;
                    if value < get(app.xmin_responseRaster, 'value')
                        app.xmax_responseRaster.Value = get(app.xmin_responseRaster, 'value');
                    else
                    end
        end

        % Value changed function: AdvanceonCorrectCheckBox
        function AdvanceonCorrectCheckBoxValueChanged(app, event)
                    value = app.AdvanceonCorrectCheckBox.Value;
                    app.AdvanceOnCorrectFlag = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.rasterResponse_axes = uiaxes(app.UIFigure);
            title(app.rasterResponse_axes, 'Response Raster')
            xlabel(app.rasterResponse_axes, 'Time (ms)')
            ylabel(app.rasterResponse_axes, 'Number of Trials')
            app.handleGrasp_axes = uiaxes(app.UIFigure);
            title(app.handleGrasp_axes, 'Handle Grasping')
            xlabel(app.handleGrasp_axes, 'X')
            ylabel(app.handleGrasp_axes, 'Y')
            app.StartExperiment_Button = uibutton(app.UIFigure, 'state');
            app.StartExperiment_Button.ValueChangedFcn = createCallbackFcn(app, @StartExperiment_ButtonValueChanged, true);
            app.StartExperiment_Button.Text = 'Start Experiment';
            app.SessionTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.SessionTypeButtonGroup.Title = 'Session Type';
            app.TactileDiscriminationButton = uiradiobutton(app.SessionTypeButtonGroup);
            app.TactileDiscriminationButton.Text = 'Tactile Discrimination';
            app.TactileDiscriminationButton.Value = true;
            app.GraspingTrainingButton = uiradiobutton(app.SessionTypeButtonGroup);
            app.GraspingTrainingButton.Text = 'Grasping Training';
            app.DetectionButton = uiradiobutton(app.SessionTypeButtonGroup);
            app.DetectionButton.Text = 'Detection';
            app.RandomRewardButton = uiradiobutton(app.SessionTypeButtonGroup);
            app.RandomRewardButton.Text = 'Random Reward';
            app.TactileDiscrminationwDistractorsButton = uiradiobutton(app.SessionTypeButtonGroup);
            app.TactileDiscrminationwDistractorsButton.Text = 'Tactile Discrmination w/ Distractors';
            app.HandleandSpoutDetectorsSwitch = uiswitch(app.UIFigure, 'slider');
            app.HandleandSpoutDetectorsSwitch.ValueChangedFcn = createCallbackFcn(app, @HandleandSpoutDetectorsSwitchValueChanged, true);
            app.InitializeDetectorsLabel = uilabel(app.UIFigure);
            app.InitializeDetectorsLabel.Text = 'Initialize Detectors';
            app.HandleandSpoutDetectorsLamp = uilamp(app.UIFigure);
            app.DirectorytoSaveButton = uibutton(app.UIFigure, 'push');
            app.DirectorytoSaveButton.ButtonPushedFcn = createCallbackFcn(app, @DirectorytoSaveButtonPushed, true);
            app.DirectorytoSaveButton.Text = 'Directory to Save';
            app.SaveDirLabel = uilabel(app.UIFigure);
            app.SaveDirLabel.Text = 'N/A';
            app.LoadExperimentalFileButton = uibutton(app.UIFigure, 'push');
            app.LoadExperimentalFileButton.Text = 'Load Experimental File';
            app.NotLoadedLabel = uilabel(app.UIFigure);
            app.NotLoadedLabel.Text = 'Not Loaded';
            app.ExperimentalFileLamp = uilamp(app.UIFigure);
            app.ParametersforRandomRewardAssociationTrainingSessionsPanel = uipanel(app.UIFigure);
            app.ParametersforRandomRewardAssociationTrainingSessionsPanel.Title = 'Parameters for Random Reward & Association Training Sessions';
            app.StimtoRewardTimeDiffmsEditFieldLabel = uilabel(app.ParametersforRandomRewardAssociationTrainingSessionsPanel);
            app.StimtoRewardTimeDiffmsEditFieldLabel.Text = 'Stim to Reward Time Diff. (ms)';
            app.StimtoRewardTimeDiffmsEditField = uieditfield(app.ParametersforRandomRewardAssociationTrainingSessionsPanel, 'numeric');
            app.StimtoRewardTimeDiffmsEditField.Value = 500;
            app.LoadStimulusFileButton = uibutton(app.ParametersforRandomRewardAssociationTrainingSessionsPanel, 'push');
            app.LoadStimulusFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStimulusFileButtonPushed, true);
            app.LoadStimulusFileButton.Text = 'Load Stimulus File';
            app.LoadStimulusFileLamp = uilamp(app.ParametersforRandomRewardAssociationTrainingSessionsPanel);
            app.NumberoftrialsEditFieldLabel = uilabel(app.ParametersforRandomRewardAssociationTrainingSessionsPanel);
            app.NumberoftrialsEditFieldLabel.Text = 'Number of trials';
            app.NumberoftrialsEditField = uieditfield(app.ParametersforRandomRewardAssociationTrainingSessionsPanel, 'numeric');
            app.NumberoftrialsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberoftrialsEditFieldValueChanged, true);
            app.NumberoftrialsEditField.Value = 100;
            app.StimulusfileLabel = uilabel(app.ParametersforRandomRewardAssociationTrainingSessionsPanel);
            app.StimulusfileLabel.Text = 'Stimulus file';
            app.ITIStartmsEditField_2Label = uilabel(app.ParametersforRandomRewardAssociationTrainingSessionsPanel);
            app.ITIStartmsEditField_2Label.Text = 'ITI Start (ms)';
            app.ITIStartmsEditField = uieditfield(app.ParametersforRandomRewardAssociationTrainingSessionsPanel, 'numeric');
            app.ITIStartmsEditField.Value = 5000;
            app.ITIEndmsLabel = uilabel(app.ParametersforRandomRewardAssociationTrainingSessionsPanel);
            app.ITIEndmsLabel.Text = 'ITI End (ms)';
            app.ITIEndmsEditField = uieditfield(app.ParametersforRandomRewardAssociationTrainingSessionsPanel, 'numeric');
            app.ITIEndmsEditField.Value = 10000;
            app.GraspdurationmsLabel = uilabel(app.ParametersforRandomRewardAssociationTrainingSessionsPanel);
            app.GraspdurationmsLabel.Text = 'Grasp duration (ms)';
            app.GraspTimeOutEditField = uieditfield(app.ParametersforRandomRewardAssociationTrainingSessionsPanel, 'numeric');
            app.GraspTimeOutEditField.Value = 1000;
            app.BlockTrialsPanel = uipanel(app.ParametersforRandomRewardAssociationTrainingSessionsPanel);
            app.BlockTrialsPanel.Title = 'Block Trials';
            app.DeliverTrialsonBlocksCheckBox = uicheckbox(app.BlockTrialsPanel);
            app.DeliverTrialsonBlocksCheckBox.Text = 'Deliver Trials on Blocks';
            app.MinBlockLabel = uilabel(app.BlockTrialsPanel);
            app.MinBlockLabel.Text = 'Min Block';
            app.minBlock_numberEditField = uieditfield(app.BlockTrialsPanel, 'numeric');
            app.minBlock_numberEditField.Value = 2;
            app.MaxBlockLabel = uilabel(app.BlockTrialsPanel);
            app.MaxBlockLabel.Text = 'Max Block';
            app.maxBlock_numberEditField = uieditfield(app.BlockTrialsPanel, 'numeric');
            app.maxBlock_numberEditField.Value = 8;
            app.AdvanceonCorrectCheckBox = uicheckbox(app.ParametersforRandomRewardAssociationTrainingSessionsPanel);
            app.AdvanceonCorrectCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdvanceonCorrectCheckBoxValueChanged, true);
            app.AdvanceonCorrectCheckBox.Text = 'Advance on Correct';
            app.RewardParametersPanel = uipanel(app.UIFigure);
            app.RewardParametersPanel.Title = 'Reward Parameters';
            app.RewardSizeuLEditFieldLabel = uilabel(app.RewardParametersPanel);
            app.RewardSizeuLEditFieldLabel.Text = 'Reward Size (uL)';
            app.RewardSizeuLEditField = uieditfield(app.RewardParametersPanel, 'numeric');
            app.RewardSizeuLEditField.Limits = [0 20];
            app.RewardSizeuLEditField.RoundFractionalValues = 'on';
            app.RewardSizeuLEditField.ValueChangedFcn = createCallbackFcn(app, @RewardSizeuLEditFieldValueChanged, true);
            app.RewardSizeuLEditField.Value = 6;
            app.SuckInLiquid_Button = uibutton(app.RewardParametersPanel, 'state');
            app.SuckInLiquid_Button.ValueChangedFcn = createCallbackFcn(app, @SuckInLiquid_ButtonValueChanged, true);
            app.SuckInLiquid_Button.Text = 'Load liquid';
            app.PushoutLiquid_Button = uibutton(app.RewardParametersPanel, 'state');
            app.PushoutLiquid_Button.ValueChangedFcn = createCallbackFcn(app, @PushoutLiquid_ButtonValueChanged, true);
            app.PushoutLiquid_Button.Text = 'Flush liquid';
            app.DeliverRewardButton = uibutton(app.RewardParametersPanel, 'push');
            app.DeliverRewardButton.ButtonPushedFcn = createCallbackFcn(app, @DeliverRewardButtonPushed, true);
            app.DeliverRewardButton.Text = 'Deliver Reward';
            app.Switch = uiswitch(app.RewardParametersPanel, 'slider');
            app.Switch.Items = {'Left', 'Right'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Left';
            app.ExperimentalFileLabel = uilabel(app.UIFigure);
            app.ExperimentalFileLabel.Text = 'Experimental File NA';
            app.PostWeightgEditFieldLabel = uilabel(app.UIFigure);
            app.PostWeightgEditFieldLabel.Text = 'Post Weight (g)';
            app.MousepostWeight_editBox = uieditfield(app.UIFigure, 'numeric');
            app.MouseIDEditFieldLabel = uilabel(app.UIFigure);
            app.MouseIDEditFieldLabel.Text = 'Mouse ID';
            app.MouseIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.MouseIDEditField.Limits = [0 1000];
            app.MouseIDEditField.ValueChangedFcn = createCallbackFcn(app, @MouseIDEditFieldValueChanged, true);
            app.PreWeightgEditFieldLabel = uilabel(app.UIFigure);
            app.PreWeightgEditFieldLabel.Text = 'Pre Weight (g)';
            app.PreWeightgEditField = uieditfield(app.UIFigure, 'numeric');
            app.PreWeightgEditField.ValueChangedFcn = createCallbackFcn(app, @PreWeightgEditFieldValueChanged, true);
            app.StopExperimentButton = uibutton(app.UIFigure, 'push');
            app.StopExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StopExperimentButtonPushed, true);
            app.StopExperimentButton.Text = 'Stop Experiment';
            app.OverheadLightsSwitchLabel = uilabel(app.UIFigure);
            app.OverheadLightsSwitchLabel.Text = {'Overhead'; 'Lights'};
            app.OverheadLightsSwitch = uiswitch(app.UIFigure, 'toggle');
            app.OverheadLightsSwitch.ValueChangedFcn = createCallbackFcn(app, @OverheadLightsSwitchValueChanged, true);
            app.CameraLightsLabel = uilabel(app.UIFigure);
            app.CameraLightsLabel.Text = {'Camera '; 'Lights'; ''};
            app.CameraLightsSwitch = uiswitch(app.UIFigure, 'toggle');
            app.CameraLightsSwitch.ValueChangedFcn = createCallbackFcn(app, @CameraLightsSwitchValueChanged, true);
            app.CueLightSwitchLabel = uilabel(app.UIFigure);
            app.CueLightSwitchLabel.Text = {'Cue '; 'Light'; ''};
            app.CueLightSwitch = uiswitch(app.UIFigure, 'toggle');
            app.CueLightSwitch.ValueChangedFcn = createCallbackFcn(app, @CueLightSwitchValueChanged, true);
            app.xminEditFieldLabel = uilabel(app.UIFigure);
            app.xminEditFieldLabel.Text = 'x-min';
            app.xmin_responseRaster = uieditfield(app.UIFigure, 'numeric');
            app.xmin_responseRaster.ValueChangedFcn = createCallbackFcn(app, @xmin_responseRasterValueChanged, true);
            app.xmin_responseRaster.Value = -5000;
            app.xmaxLabel = uilabel(app.UIFigure);
            app.xmaxLabel.Text = 'x-max';
            app.xmax_responseRaster = uieditfield(app.UIFigure, 'numeric');
            app.xmax_responseRaster.ValueChangedFcn = createCallbackFcn(app, @xmax_responseRasterValueChanged, true);
            app.xmax_responseRaster.Value = 5000;
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_experiment_Sept_23

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end